classdef Ranalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RanalyserUIFigure           matlab.ui.Figure
        AdvancedsettingsMenu        matlab.ui.container.Menu
        LoadMenu                    matlab.ui.container.Menu
        CalcSEvolumeMenu            matlab.ui.container.Menu
        PlotMenu                    matlab.ui.container.Menu
        CumulativecurveMenu         matlab.ui.container.Menu
        STDMenu                     matlab.ui.container.Menu
        StatisticsinfoMenu          matlab.ui.container.Menu
        KerneldensityMenu           matlab.ui.container.Menu
        AnalysissettingsMenu        matlab.ui.container.Menu
        ConvergencethresholdMenu    matlab.ui.container.Menu
        MaxiterationMenu_3          matlab.ui.container.Menu
        RandomsamplingMenu          matlab.ui.container.Menu
        EnableAreaMenu              matlab.ui.container.Menu
        AutorescaleMenu             matlab.ui.container.Menu
        GaussianfitMenu             matlab.ui.container.Menu
        GM_Showiteration            matlab.ui.container.Menu
        RealfitMenu                 matlab.ui.container.Menu
        RF_Showiteration            matlab.ui.container.Menu
        FitmethodMenu               matlab.ui.container.Menu
        EvaluatewithKStestMenu      matlab.ui.container.Menu
        ToolMenu                    matlab.ui.container.Menu
        GMMoptimizationMenu         matlab.ui.container.Menu
        infoMenu                    matlab.ui.container.Menu
        VersionMenu                 matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        DistributionTab             matlab.ui.container.Tab
        RightPanel                  matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        table_type                  matlab.ui.control.DropDown
        TabletypeLabel              matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
        LeftPanel                   matlab.ui.container.Panel
        DataacquisitionPanel        matlab.ui.container.Panel
        MaxLabel_2                  matlab.ui.control.Label
        MinLabel_2                  matlab.ui.control.Label
        Ytype                       matlab.ui.control.DropDown
        CounttypeLabel              matlab.ui.control.Label
        YaxisheightLabel            matlab.ui.control.Label
        XaxisbinsLabel              matlab.ui.control.Label
        AutoscalingButton           matlab.ui.control.Button
        Analysecolumn               matlab.ui.control.DropDown
        AnalysecolmunLabel          matlab.ui.control.Label
        scaleDropDown               matlab.ui.control.DropDown
        ScaleLabel                  matlab.ui.control.Label
        MaxLabel                    matlab.ui.control.Label
        StepLabel                   matlab.ui.control.Label
        B_max                       matlab.ui.control.NumericEditField
        B_step                      matlab.ui.control.NumericEditField
        B_min                       matlab.ui.control.NumericEditField
        Y_max                       matlab.ui.control.NumericEditField
        Y_min                       matlab.ui.control.NumericEditField
        MinLabel                    matlab.ui.control.Label
        ExportPanel                 matlab.ui.container.Panel
        DisplayedhistogramCheckBox  matlab.ui.control.CheckBox
        DisplayedtableCheckBox      matlab.ui.control.CheckBox
        SavedataButton              matlab.ui.control.Button
        AnalysersettingsPanel       matlab.ui.container.Panel
        Loadendembers               matlab.ui.control.Button
        Numberofclass               matlab.ui.control.NumericEditField
        ComponentsLabel             matlab.ui.control.Label
        MethodDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel         matlab.ui.control.Label
        AnalyseButton               matlab.ui.control.Button
        LoadPanel                   matlab.ui.container.Panel
        data_label                  matlab.ui.control.Label
        LoadcsvdataButton           matlab.ui.control.Button
        batch                       matlab.ui.control.CheckBox
        ComparisonTab               matlab.ui.container.Tab
        NBLabel                     matlab.ui.control.Label
        NALabel                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.EditField
        evalC                       matlab.ui.control.CheckBox
        minNum                      matlab.ui.control.NumericEditField
        ResampleC                   matlab.ui.control.CheckBox
        BLabel                      matlab.ui.control.Label
        ALabel                      matlab.ui.control.Label
        descriptionLabel            matlab.ui.control.Label
        TestButton                  matlab.ui.control.Button
        NormaldistributionLabel     matlab.ui.control.Label
        BColumn                     matlab.ui.control.DropDown
        AColumn                     matlab.ui.control.DropDown
        BLabel_2                    matlab.ui.control.Label
        BLoad                       matlab.ui.control.Button
        ALabel_2                    matlab.ui.control.Label
        ALoad                       matlab.ui.control.Button
        TestMethod                  matlab.ui.control.DropDown
        MethodDropDown_2Label       matlab.ui.control.Label
        eval                        matlab.ui.control.UIAxes
        qqax                        matlab.ui.control.UIAxes
        CompPlot                    matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        OriginaldataMenu            matlab.ui.container.Menu
        GMresultMenu                matlab.ui.container.Menu
        RealfitresultMenu           matlab.ui.container.Menu
        RealendmembersMenu          matlab.ui.container.Menu
        HistogramdataMenu           matlab.ui.container.Menu
    end

%==========================================================================
        %v4.9.0: Calculation of SE Volume was changed.
        %v5.0.0: Add KS test
        %v5.0.1: Fix area fitting and area cumlative plot
        %v5.0.1: add showing BIC
        %v5.1.0: add showing standard error
        %v5.1.1: mod SE calc params
%==========================================================================

    properties (Access = private)
        version = 'v5.2.0 (25 Dec 2025)';
        %plot
        scale = 'Liner';
        plot_range = [0, 0.01, 1];
        
        %analysis settings
        max_iteration = 20000;
        conv_prec     = 0.000001;
        bins          = [0,0.02,1];
        realfit_mode  = 'rmse';
        
        %GM model analysis
        N_random = 500;
        
        %data
        data_path = []; % Description
        raw_data = [];
        raw_endmembers = [];
        GM_data = [];
        RF_data = [];

        compA = [];
        compB = [];
        compSizeTest = [];
    end
 %=========================================================================   
    methods (Access = private)
        function [] = plotResult(app, method, scale)
            if isempty(app.raw_data)
                return
            end

            cla(app.UIAxes,'reset');
            % read plot
            Traw   = app.raw_data;
            switch method
                case 'Gaussian mixture model'
                    T      = app.GM_data.T;%[member_name, contribution, mu, sigma, AIC, evaluation]
                    X_bins = app.GM_data.X_bins;
                    Y_raw  = app.GM_data.Y_raw;
                    Y_hist = app.GM_data.Y_hist;
                    Y_each = app.GM_data.Z_hist;
                    xname  = app.GM_data.xname;
                    yname  = app.GM_data.yname;
                    r_idx  = app.GM_data.random_idx;
                    N_fit  = size(Y_each, 2);
                    contribution = T.Contribution;
                    prec         = T.AIC(1);
                    prec2        = T.BIC(1);
                    mu           = T.Dist_mu;
                    sigma        = T.Dist_sigma;
                    member_name  = T.Name;
                    type = 'GM';
                case {'Real fit','Real fit (N Prob)'}
                    T      = app.RF_data.T;%[member_name, contribution, mu, sigma, RMSE, evaluation] 
                    X_bins = app.RF_data.X_bins;
                    Y_raw  = app.RF_data.Y_raw;
                    Y_hist = app.RF_data.Y_hist;
                    Y_each = app.RF_data.Z_hist;
                    xname  = app.RF_data.xname;
                    yname  = app.RF_data.yname;
                    r_idx  = app.RF_data.random_idx;
                    N_fit  = size(Y_each, 2);
                    contribution = T.Contribution;
                    prec         = T.(app.realfit_mode)(1);
                    mu           = T.Dist_mu;
                    sigma        = T.Dist_sigma;
                    member_name  = T.Name;
                    type ='RF';
                otherwise
                    %plot without analysis
                    X_bins   = [app.bins(1): app.bins(2) :app.bins(3)];    
                    T        = Traw;
                    Y        = app.convScale(T.(app.Analysecolumn.Value),app.scale);
                    type     = 'Other';
                    [r_idx, Y_raw,Y_hist, xname, yname ]  = app.makeHistData();
                    if isempty(r_idx)
                        return
                    end
            end
             
            %convert Y axis to %
            Y_hist = Y_hist*100;
         

            % make plot data
            BIN   = X_bins(1:end-1) + mean(diff(X_bins))/2;
            BIN_WIDTH = 0.9;
            [targetPDF, ~] = ksdensity(Y_raw, BIN);
            targetPDF = 100 * targetPDF / sum(targetPDF, 2);
            %BIN   = X_bins(1:end-1);

            % plot
            if app.bins(1)>=app.bins(3)
                return
            end
            colororder(app.UIAxes, [0.10 0.40 0.99; 0.357 0.435 0.22]);
            yyaxis(app.UIAxes, 'right');
                yticks(app.UIAxes,[]);
            yyaxis(app.UIAxes, 'left'); 
                bar(app.UIAxes, BIN, Y_hist, 'FaceColor',[0.5843    0.8157    0.9882], 'EdgeColor', 'none', 'FaceAlpha',0.7, 'BarWidth', BIN_WIDTH); %main data
                    ylabel(app.UIAxes, yname, 'interpreter','none');
                    xlabel(app.UIAxes, xname, 'interpreter','none');
                    grid(app.UIAxes, 'on');
                    xlim(app.UIAxes, [X_bins(1), X_bins(end)]);
                    ylim(app.UIAxes,[0,app.Y_max.Value]);
                    hold(app.UIAxes, 'on');
                switch app.KerneldensityMenu.Checked
                    case 'on'
                        plot(app.UIAxes, BIN, targetPDF,'--', 'Color','blue');
                        hold(app.UIAxes, 'on');
                end
                
                switch type
                    case {'GM','RF'}
                        if N_fit ~=0
                            %plot each fitting distribution
                            PDF_D = zeros(size(X_bins,1),1);
                            for i=1:N_fit
                                %make data
                                name = app.data_label.Text;
                                switch method
                                    case 'Gaussian mixture model'
                                        Prob = contribution(i) * Y_each(:,i);%pdf('normal', X_bins, mu(i), sigma(i)) * step
                                        lb1  = strcat(member_name{i});
                                        lb2  = strcat('[',num2str(round(contribution(i)*100,1)),'%]');
                                        tt   = strcat(name, '_of GMM (AIC=',num2str(prec),'; BIC=',num2str(prec2),')');
                                    case {'Real fit','Real fit (N Prob)'}
                                        Prob = contribution(i) * Y_each(:,i);
                                        lb1  = strcat(member_name{i});
                                        lb2  = strcat('[',num2str(round(contribution(i)*100,1)),'%]');
                                        switch app.realfit_mode
                                            case 'aic'
                                                fnc_name = 'AIC';
                                            case 'bic'
                                                fnc_name = 'BIC';
                                            case 'logrmse'
                                                fnc_name = 'RMSLE';
                                            otherwise
                                                fnc_name = 'RMSE';
                                        end
                                        tt   = strcat(name, '_of Real fit[',app.realfit_mode,'](',fnc_name,'=',num2str(round(prec,5)),')');
                                end
                                %convert Y axis to %
                                Prob = Prob*100;

                                %plot
                                plot(app.UIAxes, BIN, Prob,'m:','LineWidth',2);
                                    hold(app.UIAxes, 'on');
                                    text(app.UIAxes, mu(i), max(Prob)*0.7, lb1, 'FontSize',12, 'HorizontalAlignment','center','interpreter','none');
                                    hold(app.UIAxes, 'on');
                                    text(app.UIAxes, mu(i), max(Prob)*0.7 - max(Y_hist)*0.05, lb2, 'FontSize',12, 'HorizontalAlignment','center','interpreter','none');
                                    hold(app.UIAxes, 'on');
                                %make cumlative curve
                                PDF_D = PDF_D + Prob';
                            end
                            %plot maeged line
                            plot(app.UIAxes, BIN, PDF_D, 'r-', 'LineWidth',1);
                                hold(app.UIAxes, 'off');
                            title(app.UIAxes, tt,'interpreter','none');
                        end
                end

                %plot cumulative curve & STD
                %make data
                %[Ysort,sidx] = sort(Y_raw);%min->max
                TrawTarget = Traw.(app.Analysecolumn.Value);
                %[Ysort,sidx] = sort(TrawTarget);%min->max
                [Ysort, sidx] = sort(TrawTarget(r_idx));%min->max
                Ysort        = app.convScale(Ysort,app.scale);
                Ysort = Ysort(find(~isnan(Ysort)));
                sidx  = sidx(find(~isnan(Ysort)));

                switch app.Ytype.Value
                    case 'Count'
                        Ycum  = cumsum(ones(size(Ysort)));
                        Ycum  = 100*(Ycum./Ycum(end));
                        Ysortval = ones(numel(sidx),1);
                    case 'Area'
                        cidx = cellfind_ra(Traw.Properties.VariableNames,'AREA',0);
                        Ysortval = Traw{r_idx(sidx),cidx(1)};
                        Ycum  = cumsum(Ysortval);
                        Ycum  = 100*(Ycum./Ycum(end));
                        
                    case 'SEVolume'
                        cidx = cellfind_ra(Traw.Properties.VariableNames,'SEVolume',0);
                        if isempty(cidx)
                            uialert(app.RanalyserUIFigure,'There is no SE Volume.','Warning','Icon','warning');
                            return
                        end

                        Ysortval = Traw{r_idx(sidx), cidx(1)};
                        Ycum  = cumsum(Ysortval);
                        Ycum  = 100*(Ycum./Ycum(end));
                end
                
                
                %plot
                switch app.CumulativecurveMenu.Checked
                    case 'on'
                        yyaxis(app.UIAxes, 'right'); 
                        ylabel(app.UIAxes, 'Cumlative [%]', 'interpreter','none');
                        yticks(app.UIAxes,[0:10:100]);
                        yticklabels(app.UIAxes, num2str([0:10:100]'));
                        ylim(app.UIAxes, [0 100]);

                        p     = [10,25,50,75,90]';
                        Yp    = Ysort(cumpercentile_ra(Ysortval, p/100));
                        Yp_L  = strcat('[',num2str(p),'%,',num2str(round(Yp,2)),']');
                        cm    = [0.357 0.435 0.22];
                        %cumlative
                        hold(app.UIAxes, 'on');
                        yyaxis(app.UIAxes, 'right');
                            plot(app.UIAxes, Ysort, Ycum,'Color', cm,'linewidth',1);
                                ylabel(app.UIAxes, 'Cumlative [%]');
                                    hold(app.UIAxes, 'on');
                                scatter(app.UIAxes, Yp, p, 10,'o', 'MarkerEdgeColor', cm,'linewidth',1);
                                    hold(app.UIAxes, 'on');
                                text(app.UIAxes, Yp*1.05, p, Yp_L, 'fontsize',10);
                                    hold(app.UIAxes, 'off');
                end

                %plot histogramSTD
                switch app.STDMenu.Checked
                    case 'on'
                        %percentile
                        p     = [0.00, 15.85, 50.00, 84.15, 100.00];
                        Yp    = Ysort(cumpercentile_ra(Ysortval, p/100));
                        
                        ty = (max(Y_hist) * 0.05) * ones(5,1);
                        %plot
                        yyaxis(app.UIAxes, 'left');
                            hold(app.UIAxes, 'on')
                        plot(app.UIAxes, Yp([1,5]), ty([1,5]),'-k','linewidth',1.5);%Full range
                            hold(app.UIAxes, 'on')
                        plot(app.UIAxes, Yp([2,4]), ty([2,4]),'-b','linewidth',1.5);%1 sigma range
                            hold(app.UIAxes, 'on')
                        scatter(app.UIAxes, Yp([2:4]), ty([2:4]),'+','k','linewidth',0.5);%point
                            hold(app.UIAxes, 'on')
                         
                         xdf = 0.020*(abs(BIN(end)-BIN(1)));
                         ydf = 0.025*(max(Y_hist));
                        text(app.UIAxes, Yp(2)-xdf, ty(2)-ydf, num2str(round(Yp(2),2)));%lower std
                            hold(app.UIAxes, 'on')
                        text(app.UIAxes, Yp(4)-xdf, ty(4)-ydf, num2str(round(Yp(4),2)));%upper std
                            hold(app.UIAxes, 'on')
                        text(app.UIAxes, Yp(3)-xdf, ty(3)-ydf, num2str(round(Yp(3),2)));%median
                            hold(app.UIAxes, 'off')
                        text(app.UIAxes, Yp(1)-xdf, ty(1)-ydf, num2str(round(Yp(1),2)));%min val
                            hold(app.UIAxes, 'on')
                        text(app.UIAxes, Yp(5)-xdf, ty(5)-ydf, num2str(round(Yp(5),2)));%max val
                            hold(app.UIAxes, 'on')
                end

                %plot info
                %plot position
                tx = BIN(1) + abs(BIN(1))*0.05;
                tx = repmat(tx,10,1);
                ty = (app.Y_max.Value - [1:10]*(app.Y_max.Value*0.05))';
                hold(app.UIAxes, 'on')
                yyaxis(app.UIAxes, 'left');
                    text(app.UIAxes, tx(1),ty(1),strcat('N = ',num2str(numel(r_idx))));

                switch app.StatisticsinfoMenu.Checked
                    case 'on'
                        Y_median   = median(Y_raw);
                        Y_mean     = mean(Y_raw);
                        Y_skewness = skewness(Y_raw);
                        Y_kurtosis = kurtosis(Y_raw);
                        [~,Midx] = max(Y_hist);
                        Y_mode     = BIN(Midx);
                        Y_95error  = 1.96*sqrt((std(Y_raw)^2)/numel(Y_raw));%95.5 2, 99.7 3, 95 1.96, 99 2.58
                        Y_99error  = 2.58*sqrt((std(Y_raw)^2)/numel(Y_raw));
                        
                        hold(app.UIAxes, 'on')
                        yyaxis(app.UIAxes, 'left');
                            text(app.UIAxes, tx(2),ty(2),strcat('SE(95%) =±',num2str(Y_95error )));
                            hold(app.UIAxes, 'on')
                            %text(app.UIAxes, tx(2),ty(2),strcat('SE(99%) =±',num2str(Y_99error )));    
                            %hold(app.UIAxes, 'on')
                            text(app.UIAxes, tx(3),ty(3),strcat('Mean =',num2str(Y_mean)));
                            hold(app.UIAxes, 'on')
                            text(app.UIAxes, tx(4),ty(4),strcat('Median =',num2str(Y_median)));
                            hold(app.UIAxes, 'on')
                            text(app.UIAxes, tx(5),ty(5),strcat('Mode =',num2str(Y_mode)));
                            hold(app.UIAxes, 'on')
                            text(app.UIAxes, tx(6),ty(6),strcat('Skewness =',num2str(Y_skewness)));
                            hold(app.UIAxes, 'on')
                            text(app.UIAxes, tx(7),ty(7),strcat('Kurtosis =',num2str(Y_kurtosis)));
                            hold(app.UIAxes, 'off')

                end
        end
  %========================================================================      
        function Y = convScale(app, Y, type)
            switch type
                case 'Liner'
                    Y = Y;
                case 'Ln'
                    %long10
                    Y = log(Y);
                case 'Phi'
                    %phi scale(cm -> mm)
                    Y = -log2(Y*10);
            end
        end
  %=======================================================================
        function [M, V] = convMV(app, m, s, type)
            %convert ln mu&sigma parameters to mean%variance
            %m: mu (mean)
            %v: sigma (std)
            if size(s,1)>size(s,2)
                s=s';
            end
            if size(m,1)>size(m,2)
                m=m';
            end

            switch type
                case 'Ln'
                    M = transpose(exp(m + ((s).^2)/2));
                    V = transpose(exp((2.*m)+(s.^2)).*(exp(s.^2)-1));
                case 'Liner'
                    M = transpose(m);
                    V = transpose(s.^2);
                case 'Phi'
                    M = transpose(nan(size(m)));
                    V = transpose(nan(size(s)));
            end
        end
        %=========================================================================
        function [] = clearCalcedData(app)
            %reset calced data
            app.GM_data = [];
            app.RF_data = [];

            %reset table
            app.table_type.Value = 'Original';
            app.UITable.Data = [];
            app.UITable.Data = app.raw_data;
        end
        %=========================================================================
        function [r_idx, Yweighted,Y_hist, xname, yname ] = makeHistData(app)
            %general settings
            stp             = app.bins(2);
            bin_min         = app.bins(1);
            bin_max         = app.bins(3);
            bins            = [bin_min:stp:bin_max];
            
            %get data
            T   = app.raw_data;
            Y   = T.(app.Analysecolumn.Value);
            Idx = find(not(isnan(Y)));%omit zero values
            Y   = Y(Idx);
            
            %convert length scale
            Y = app.convScale(Y, app.scale);
            
            %random sampling
            switch app.RandomsamplingMenu.Checked
                case 'on'
                    %randomsampling
                    N_random = app.N_random;
                    if N_random>length(Y)
                        uialert(app.RanalyserUIFigure,strcat('Sampling size is larger than original data. Please input less than',num2str(length(Y))),'Warning','Icon','warning');
                        r_idx=[];
                        Yweighted = [];
                        Y_hist =[];
                        xname =[];
                        yname = [];
                        return
                    end
                    r_idx = randperm(length(Y), N_random);
                    Y = Y(r_idx);
                case 'off'
                    r_idx = [1:length(Y)]';
                    %skip
            end
            
            %make target PDF
            Y_hist = zeros(length(bins)-1, 1);
            
            Yweighted = [];
            switch app.Ytype.Value
                case 'Count'
                    for i = 1:length(bins)-1
                        Y_hist(i,1) = length(find(bins(i)<=Y & bins(i+1)>Y))/length(Y);
                    end
                    Yweighted = Y;
                    yname  = strcat('Probability (Counts) [%] ');%yname  = strcat('Probability [%] (N=',num2str(size(Yweighted,1)),')');
                case 'Area'
                    cidx = cellfind_ra(T.Properties.VariableNames,'AREA',0);
                    for i = 1:length(bins)-1
                        yc = find(Y>=bins(i) & Y<bins(i+1));
                        if isempty(yc)==0
                            Y_hist(i,1) = sum(T{r_idx(yc),cidx(1)},1)/sum(T{:,cidx(1)},1);
                            re_idx = randi(numel(yc),round(length(Y)*Y_hist(i,1)),1);
                            
                            Yweighted = [Yweighted; Y(yc(re_idx))];
                        end
                    end
                    yname  = strcat('Probability (Area) [%] ');
                case 'SEVolume'
                    cidx = cellfind_ra(T.Properties.VariableNames,'SEVolume',0);
                    if isempty(cidx)
                        uialert(app.RanalyserUIFigure,'There is no SE Volume.','Warning','Icon','warning')
                        r_idx=[];
                        Yweighted = [];
                        Y_hist =[];
                        xname =[];
                        yname = [];
                        return
                    end

                    for i = 1:length(bins)-1
                        yc = find(Y>=bins(i) & Y<bins(i+1));
                        if isempty(yc)==0
                            Y_hist(i,1) = sum(T{r_idx(yc),cidx(1)},1)/sum(T{:,cidx(1)},1);

                            re_idx = randi(numel(yc),round(length(bins)*Y_hist(i,1)),1);
                            
                            Yweighted = [Yweighted; Y(yc(re_idx))];
                        end
                    end
                    yname  = strcat('Probability (SE Volume) [%]');
            end
            xname  = strcat(app.Analysecolumn.Value,'(',app.scale,')');
            
        end
        
        function results = plotComparisonData(app, Aidx, Bidx)
            if nargin < 2
                Aidx = [];
            end
            if nargin < 3
                Bidx = [];
            end

            %initiarlse
            cla(app.CompPlot);
            cla(app.qqax);
            cla(app.eval);

            % plot comparison panel data
            name = ["",""];
            if ~isempty(app.compA)
                %get data
                name(1) = "A";

                if length(Aidx)>1
                    a = app.getNeutralData(app.compA, app.AColumn.Value, Aidx);  
                else
                    if app.ResampleC.Value == 1         
                        a = app.getNeutralData(app.compA, app.AColumn.Value, app.minNum.Value);  
                    else
                        a = app.getNeutralData(app.compA, app.AColumn.Value, 0);  
                    end
                end

                if isempty(a)==1
                    return
                end

                %main plot A
                [ac, a_ecdf] = ecdf(a);
                plot(app.CompPlot, a_ecdf, ac, 'LineWidth', 2);     

                switch app.TestMethod.Value
                    case "AD test"
                        %main plot B
                        name(2) = "Normal distribution";
                        mu = mean(a);
                        sg = std(a);                        
                        xx = linspace(min(a), max(a), 400);
                        yy = normcdf(xx, mu, sg);

                        hold(app.CompPlot,"on");
                        plot(app.CompPlot, xx, yy, 'LineWidth', 2)

                        %qqplot
                        qqplot(app.qqax, a);
                        xlabel(app.qqax,'Theoretical Quantiles')
                        ylabel(app.qqax,'Sample Quantiles')
                        title(app.qqax,'qqplot (Sample A)')
                        grid(app.qqax, "on");

                        %eval plot                        
                        if app.evalC.Value == 1 && height(app.compSizeTest)>0
                            errorbar(app.eval, app.compSizeTest{:,1} , app.compSizeTest{:,2}, app.compSizeTest{:,3})        
                            hold(app.eval, "on")
                            scatter(app.eval, app.compSizeTest{:,1} , app.compSizeTest{:,2}, 10, "filled", MarkerFaceColor="black")
                                       
                            hold(app.eval, "on")
                            plot(app.eval, [0, max(app.compSizeTest{:,1})], [app.compSizeTest{:,4}, app.compSizeTest{:,4}], Color="red")
                            grid(app.eval, "on")
                            title(app.eval, "Lower limit of p-value truncated to 0.0005.")
                        end
                        
                        
                    otherwise
                        if ~isempty(app.compB)    
                            %main plot B
                            name(2) = "B";
                            if length(Bidx)>1
                                b = app.getNeutralData(app.compB, app.BColumn.Value, Bidx);
                            else
                                if app.ResampleC.Value == 1
                                    b = app.getNeutralData(app.compB, app.BColumn.Value, app.minNum.Value);
                                else
                                    b = app.getNeutralData(app.compB, app.BColumn.Value, 0);
                                end
                            end

                            [bc, b_ecdf] = ecdf(b);
                            
                            hold(app.CompPlot,"on");
                            plot(app.CompPlot, b_ecdf, bc, 'LineWidth', 2);

                            %qqplot
                            qqplot(app.qqax, a, b);  
                            xlabel(app.qqax,'Sample A Quantiles')
                            ylabel(app.qqax,'Sample B Quantiles')
                            title(app.qqax,'qqplot')
                            grid(app.qqax, "on");

                            %eval plot
                            if app.evalC.Value == 1
                                disp(app.compSizeTest)
                                errorbar(app.eval, app.compSizeTest{:,"num_sampleA"} , app.compSizeTest{:,"p_median"}, app.compSizeTest{:,"p_std"})        
                                hold(app.eval, "on")
                                scatter(app.eval, app.compSizeTest{:,"num_sampleA"} , app.compSizeTest{:,"p_median"}, 10, "filled", MarkerFaceColor="black")
                                           
                                hold(app.eval, "on")
                                plot(app.eval, [0, max(app.compSizeTest{:,"num_sampleA"})], [app.compSizeTest{:,"alpha"}, app.compSizeTest{:,"alpha"}], Color="red")
                                grid(app.eval, "on")
                                title(app.eval, "Resampled 1000 times for each")
                            end

                            %display                            
                        end
                end
            end

            %main plot labels
            grid(app.CompPlot,"on")
            legend(app.CompPlot,name,'Location','best')
            xlim(app.CompPlot,[0,1])
            ylim(app.CompPlot,[0,1])
        end
        
        function [data, idx] = getNeutralData(app, dataTable, targetColumn, numPerm)
            if isempty(dataTable)
                data = [];
                return
            end
            data = dataTable{:, targetColumn};
            data = data(isfinite(data));

            %if ~(isnumeric(a) || islogical(a))
            
            if length(numPerm)>1
                data = data(numPerm);
            else
                if numPerm ==0
                    idx = randperm(length(data), length(data));
                    data = data(idx);
                elseif numPerm > 0
                    if numPerm>length(data)
                        numPerm = length(data);
                    end
                    idx = randperm(length(data), numPerm);
                    data = data(idx);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %check new release
            url = 'https://api.github.com/repos/keitaroyamada/Ranalyser/releases/latest';

            try
                curVer = split(app.version,{' ','v','.','('});
                releaseInfo = webread(url);
                webVer = split(releaseInfo.tag_name,{'v','.'});

                curMaj = str2double(curVer{2}); curMin = str2double(curVer{3}); curPat = str2double(curVer{4});
                webMaj = str2double(webVer{2}); webMin = str2double(webVer{3}); webPat = str2double(webVer{4});

                
                isLatest = true;
                if  (webMaj > curMaj) || ...
                    (webMaj == curMaj && webMin > curMin) || ...
                    (webMaj == curMaj && webMin == curMin && webPat > curPat)
                    isLatest = false;
                end
                    
                
                if isLatest==false
                    q = uiconfirm(app.RanalyserUIFigure,strcat("New version available! [",releaseInfo.tag_name,"]"),"Update checker","Options",{'Get now','Later'})
                    if q=="Get now"
                        web('https://github.com/keitaroyamada/Ranalyser/releases/', '-browser');
                    end
                else
                    disp("Latest version is installed.")
                end
            catch e
                disp(strcat('Update check failed.'));
                disp(e.message)
            end
            
            %plot
            colororder(app.UIAxes, [0.10 0.40 0.99; 0.357 0.435 0.22]);
            yyaxis(app.UIAxes, 'left'); 
                xlabel(app.UIAxes, 'Analysis data', 'interpreter','none');
                ylabel(app.UIAxes, 'Probability(Counts) [%]', 'interpreter','none');
            yyaxis(app.UIAxes, 'right');
                yticks(app.UIAxes,[]);
                %ylabel(app.UIAxes, 'Cumlative(%)', 'interpreter','none');

            %app.UITable.Position=[7,7,603,180];
            %app.UITable.OuterPosition=[7,7,603,180]
            app.UITable.Units="pixels";
        end

        % Button pushed function: LoadcsvdataButton
        function LoadcsvdataButtonPushed(app, event)
            %initialise
            app.raw_data        = [];
            app.GM_data         = [];
            app.raw_endmembers  = [];
            app.RF_data         = [];
            app.UITable.Data    = [];
            cla(app.UIAxes,'reset');
            app.data_label.Text = 'No data';

            
            switch app.batch.Value
                case 1
                    %read bach
                    %get folder
                    [data_path] = uigetdir(app.data_path);
                    if data_path==0
                        return
                    else
                        app.data_path = data_path;
                    end
                    
                    %get list
                    data_list = [dir(fullfile(app.data_path, '*.csv')); dir(fullfile(app.data_path, '*.xls')); dir(fullfile(app.data_path, '*.xlsx'))];
                    
                    %read csv
                    T=[];
                    for i=1:size(data_list,1)
                        t = readtable(fullfile(app.data_path, data_list(i).name));
                        T = [T; t];
                    end
                    T.Properties.VariableNames = upper(T.Properties.VariableNames);

                    %show result
                    [~,name] = fileparts(data_list(1).folder);
                    name = strcat(name,'_marged'); 
                case 0
                    %read single file
                    %get path
                    [data_name, data_path] = uigetfile(fullfile(app.data_path, '*.csv'));
                    if data_name==0
                        return
                    else
                        app.data_path = data_path;
                    end
                    
                    %read csv
                    T  = readtable(fullfile(app.data_path, data_name));
                    T.Properties.VariableNames = upper(T.Properties.VariableNames);

                    %get name
                    [~,name,~] = fileparts(fullfile(app.data_path, data_name));
            end

            %calc SE volume
            switch app.CalcSEvolumeMenu.Checked
                case 'on'
                    Tv = cellfind_ra(T.Properties.VariableNames,'SEVolume',0);
                    if isempty(Tv)==1
                        msg = 'There is no SE volume in data. Do you want to calculate?.';
                        title = 'Confirm calc';
                        selection = uiconfirm(app.RanalyserUIFigure,msg,title,...
                                                'Options',{'Yes','No'},...
                                                'DefaultOption',2);
                        switch selection
                            case 'Yes'
                                %without sevolume
                                Ta = cellfind_ra(T.Properties.VariableNames,'AREA',0)

                                if isempty(Ta)==1
                                    [tid,tf] = listdlg('PromptString',{'Please chose calculation source area size',''},'ListString',T.Properties.VariableNames);
                                else
                                    [indx,tf] = listdlg('PromptString',{'Please chose calculation source area size',''},'ListString',T.Properties.VariableNames(Ta));
                                    tid = Ta(indx);
                                end
                                r = sqrt((T{:,tid})./pi);
                                SEVolume = (4/3*r.^3.*pi);
                                T = [T, table(SEVolume)];
                            case 'No'
                        end
                    end
            end

            %show result
            app.data_label.Text    = name;
            app.raw_data           = T;
            app.UITable.Data       = [];
            app.UITable.Data       = T;
            app.UITable.ColumnName = T.Properties.VariableNames;
            
            %update list
            app.table_type.Value    = 'Original';
            app.Analysecolumn.Items = app.UITable.ColumnName;
            
            %show plot
            app.plotResult('',app.scale);
            
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            save_path = app.data_path;
                
            if app.DisplayedhistogramCheckBox.Value == 1
                [save_name, save_path] = uiputfile(fullfile(save_path, '*.pdf'));
                if save_name == 0
                    return
                end
                [~,save_name,~]=fileparts(save_name);
                exportgraphics(app.UIAxes, fullfile(save_path, strcat(save_name, '.pdf')), "ContentType","vector");

                exportgraphics(app.UIAxes, fullfile(save_path, strcat(save_name, '.png')), "ContentType","image");
            end

            if app.DisplayedtableCheckBox.Value == 1
                T = app.UITable.Data
                [save_name, save_path] = uiputfile(fullfile(save_path, '*.csv'));
                if save_name == 0
                    return
                end
                writetable(T, fullfile(save_path, save_name));
            end
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            %check method
            switch app.MethodDropDown.Value
                case 'Real fit (N Prob)'
                    switch app.scale 
                        case 'Liner'
                            selection = uiconfirm(app.RanalyserUIFigure,'This method is NOT recommended. if use, please use "Liner scale of mu and sigma". Do you want to continue?','Confirm Close','Icon','warning');
                            switch selection
                                case 'Cancel'
                                    return
                            end
                        case {'Ln','Phi'}
                            selection = uiconfirm(app.RanalyserUIFigure,'This method is NOT recommended. if use, please use "Log/Phi scale of mu and sigma". Do you want to continue?','Confirm Close','Icon','warning');
                            switch selection
                                case 'Cancel'
                                    return
                            end
                    end
            end
            
            %general settings
            max_iterations  = app.max_iteration;
            conv_prec       = app.conv_prec;
            stp             = app.bins(2);
            bin_min         = app.bins(1);
            bin_max         = app.bins(3);
            bins            = [bin_min:stp:bin_max];
            
            %get data
            T   = app.raw_data;
            Y   = T.(app.Analysecolumn.Value);
            Idx = find(isnan(Y)==0);%omit zero values
            Y   = Y(Idx);
            
            %convert length scale
            Y = app.convScale(Y, app.scale);
            
            %random sampling
            switch app.RandomsamplingMenu.Checked
                case 'on'
                    %randomsampling
                    N_random = app.N_random;
                    if N_random>length(Y)
                        uialert(app.RanalyserUIFigure,strcat('Sampling size is larger than original data. Please input less than',num2str(length(Y))),'Warning','Icon','warning');
                        return
                    end
                    r_idx = randperm(length(Y), N_random);
                    Y = Y(r_idx);
                case 'off'
                    r_idx = [1:length(Y)]';
                    %skip
            end
            
            %make target PDF
            Y_hist = zeros(length(bins)-1, 1);
            Yweighted = [];
            switch app.Ytype.Value
                case 'Count'
                    for i = 1:length(bins)-1
                        Y_hist(i,1) = length(find(bins(i)<=Y & bins(i+1)>Y))/length(Y);
                    end
                    Yweighted = Y;
                case 'Area'
                    cidx = cellfind_ra(T.Properties.VariableNames,'AREA',0);
                    areaAll = sum(T{Idx(r_idx),cidx(1)});
                    for i = 1:length(bins)-1
                        yc = find(Y>=bins(i) & Y<bins(i+1));
                        if isempty(yc)==0
                            %Y_hist(i,1) = sum(T{r_idx(yc),cidx(1)},1)/sum(T{:,cidx(1)},1);
                            Y_hist(i,1) = sum(T{r_idx(yc),cidx(1)},1)/areaAll;
                            %re_idx = randperm(numel(yc),round(length(bins)*Y_hist(i,1)));
                            re_idx = randi(numel(yc),round(length(Y)*Y_hist(i,1)),1);
                            
                            Yweighted = [Yweighted; Y(yc(re_idx))];
                        end
                    end
                case 'SEVolume'
                    cidx = cellfind_ra(T.Properties.VariableNames,'SEVolume',0);
                    if isempty(cidx)
                        uialert(app.RanalyserUIFigure,'There is no SE Volume.','Warning','Icon','warning')
                        return
                    end

                    for i = 1:length(bins)-1
                        yc = find(Y>=bins(i) & Y<bins(i+1));
                        if isempty(yc)==0
                            Y_hist(i,1) = sum(T{r_idx(yc),cidx(1)},1)/sum(T{:,cidx(1)},1);
                            re_idx = randperm(numel(yc),round(length(bins)*Y_hist(i,1)));
                            
                            Yweighted = [Yweighted; Y(yc(re_idx))];
                        end
                    end
            end
            
            %% apply each method
            switch app.MethodDropDown.Value
                case 'Gaussian mixture model'
                    %% Gaussian mixture model
                    %GM initiarize
                    app.GM_data = [];
                    %settings 
                    dist_N = app.Numberofclass.Value;
                    %fitting
                    if dist_N == 0
                        %ignore fitting
                        member_name     = string(num2str(1));
                        contribution    = 1;
                        AIC             = nan;
                        mu              = mean(Yweighted);
                        sigma           = std(Yweighted, 'omitnan');
                        evaluation      = nan;
                        endmembers_hist = nan;
                        unit            = string(app.scale);
                    else
                        %fitting
                        switch app.GM_Showiteration.Checked
                            case 'on'
                                GM_set = statset('MaxIter', max_iterations, 'TolFun', conv_prec,'Display', 'iter');
                            case 'off'
                                GM_set = statset('MaxIter', max_iterations, 'TolFun', conv_prec, 'Display', 'off');
                        end
               
                        model = fitgmdist(Yweighted, dist_N, 'Options', GM_set);
                        mu    = model.mu;
                        sigma = zeros(dist_N,1);
                        step  = mean(diff(bins));
                        endmembers_hist = zeros(length(bins)-1, dist_N);
                        BIN   = bins(1:end-1) + step/2;
                        for i=1:dist_N
                            sigma(i,1) = sqrt(model.Sigma(:,:,i));%分散→標準偏差
                            endmembers_hist(:,i) = transpose(pdf('normal', BIN, mu(i), sigma(i)) * step);%make each histogram
                        end
                        
                        member_name  = string([1:dist_N]');
                        contribution = model.ComponentProportion';
                        counter      = model.NumIterations;%反復回数
                        AIC          = repmat(model.AIC,dist_N,1);
                        BIC          = repmat(model.BIC,dist_N,1);
                        if model.Converged==1
                            evaluation = repmat("Convergent",dist_N,1);
                        else
                            evaluation = repmat("Non-convergent",dist_N,1);
                        end
                        unit = repmat(string(app.scale),dist_N,1);
                    end
                                        
                    %calc mean&variance from mu&sigma params
                    [MEAN, VAR] = app.convMV(mu, sigma, app.scaleDropDown.Value);

                    %write results
                    app.GM_data.T         = table(member_name, contribution, unit, mu, sigma, MEAN, sqrt(VAR), AIC, BIC, evaluation);
                    app.GM_data.T.Properties.VariableNames = {'Name','Contribution', 'Unit', 'Dist_mu', 'Dist_sigma', 'Data_mean', 'Data_std', 'AIC', 'BIC','Fit_Evaluation'};
                    app.GM_data.Y_raw     = Yweighted;
                    app.GM_data.Y_hist    = Y_hist;
                    app.GM_data.X_bins    = bins;
                    app.GM_data.Z_hist    = endmembers_hist;
                    app.GM_data.random_idx= r_idx; 
                    %app.GM_data.Precision = AIC(1);
                    app.GM_data.xname     = strcat(app.Analysecolumn.Value,'(',app.scale,')');
                    switch app.Ytype.Value
                        case 'Count'
                            app.GM_data.yname = strcat('Probability (Counts) [%]');%app.GM_data.yname = strcat('Probability [%] (N=',num2str(size(Y,1)),')');
                        case 'Area'
                            app.GM_data.yname = strcat('Probability (Area) [%]');
                        case 'SEVolume'
                            app.GM_data.yname = strcat('Probability (SE Volume) [%]');
                    end
                    
                    %update table
                    app.table_type.Value   = 'Gaussian mixture model';
                    app.UITable.Data       = [];
                    app.UITable.Data       = app.GM_data.T;
                    app.UITable.ColumnName = app.GM_data.T.Properties.VariableNames;
                case {'Real fit','Real fit (N Prob)'}
                    %% Real model
                    %check endmember file
                    if isempty(app.raw_endmembers)==1
                        return
                    end

                    %settings
                    %read endmember data
                    %check data
                    switch app.MethodDropDown.Value
                            case 'Real fit'
                        switch app.Ytype.Value
                            case {'Area', 'SEVolume'}
                                uialert(app.RanalyserUIFigure,'Ranalyser does not support Area or SEVolume scale.','Warning','Icon','warning');
                            return
                        end
                    end

                    if any(startsWith(app.raw_endmembers.Properties.VariableNames, 'mu'))==1
                        %case endmember type is normal distribution
                        switch app.MethodDropDown.Value
                            case 'Real fit'
                                 uialert(app.RanalyserUIFigure,'These endmembers are not suitable for Real fit. Please load file whitch has each endmember values within each column.','warning','Icon','warning');
                                 return
                        end
                    else
                        %case endmember type is real distribution
                        switch app.MethodDropDown.Value
                            case 'Real fit (N Prob)'
                                uialert(app.RanalyserUIFigure,'These endmembers are not suitable for Real fit (Normal probability). Please load file whitch has columns of "Name","mu","sigma".','warning','Icon','warning');
                                return
                        end
                    end
                                
                    %if endmember is suitable
                    switch app.MethodDropDown.Value
                        %split case of legacy and now methods
                        case 'Real fit'
                            %read endmember
                            endmembers = app.convScale(app.raw_endmembers{:,:}, app.scale);
            
                            %make endmember histogram
                            member_name = string((app.raw_endmembers.Properties.VariableNames)');
                            mu          = mean(endmembers,1,'omitnan')';
                            sigma       = std(endmembers,1,'omitnan')';
                            MEAN        = mean(app.raw_endmembers{:,:},1,'omitnan')';
                            VAR         = var(app.raw_endmembers{:,:},1,'omitnan')';
            
                            %make endmember PDF
                            endmembers_hist = zeros(length(bins)-1, size(endmembers,2));
                            for e=1:size(endmembers,2)
                                for i=1:length(bins)-1
                                    endmembers_hist(i,e) = length(find(bins(i)<=endmembers(:,e) & bins(i+1)>endmembers(:,e)));
                                end
                            end
                            endmembers_hist = endmembers_hist ./ repmat(sum(endmembers_hist,1), length(endmembers_hist),1);

                            %fitting Constraint
                            x0      = zeros(1,size(endmembers_hist,2));
                            aeq     = ones(1,size(endmembers_hist,2)); 
                            beq     = 1;%x1+x2+...=1
                            lb      = zeros(1,size(endmembers_hist,2)); %lower limit
                            ub      = ones(1,size(endmembers_hist,2)); %upperlimit
                            switch app.realfit_mode
                                case {'KDE'}
                                    %input raw values
                                    fun     = @(rate) objectivefcn(rate, Yweighted, endmembers, bins, app.realfit_mode);
                                otherwise
                                    %input hist counts
                                    fun     = @(rate) objectivefcn(rate, Y_hist, endmembers_hist, bins, app.realfit_mode);
                            end
                            
                            switch app.RF_Showiteration.Checked
                                case 'on'
                                    options = optimoptions('fmincon','MaxIterations',max_iterations,'OptimalityTolerance',conv_prec,'PlotFcn','optimplotfval','Display','iter');%plot optimization
                                case 'off'
                                    options = optimoptions('fmincon','MaxIterations',max_iterations,'OptimalityTolerance',conv_prec,'PlotFcn',[],'Display','off');%plot optimization
                            end
                        case 'Real fit (N Prob)'
                            %legacy style
                            %read endmember
                            endmembers = [app.raw_endmembers.mu, app.raw_endmembers.sigma];
                            member_name = string(app.raw_endmembers{:,1});

                            %cal each params
                            switch app.scale
                                case 'Liner'
                                    mu    = endmembers(:,1); 
                                    sigma = endmembers(:,2);
                                    vari  = endmembers(:,2).^2;
                                    MEAN  = mu;
                                    VAR   = vari;
                                case 'Ln'
                                    MEAN  = endmembers(:,1);
                                    VAR   = endmembers(:,2).^2;

                                    mu    = log((MEAN.^2)./sqrt(VAR + (MEAN.^2)));
                                    sigma = sqrt(log((VAR./(MEAN.^2))+1));
                                    vari  = sigma.^2;
                                case 'Phi'
                                    disp('Not supported')
                                    return
                            end
            
                            endmembers_hist = zeros(length(bins)-1, size(endmembers,2));
                            for e=1:size(endmembers,1)
                                %make normal distribution
                                pd = makedist('Normal',mu(e), sigma(e));
                                endmembers_hist(:,e) = pdf(pd, bins(1:end-1)'+stp/2)*stp;
                            end
                            endmembers_hist = endmembers_hist ./ repmat(sum(endmembers_hist,1),length(endmembers_hist),1);

                            %fitting Constraint
                            x0      = zeros(1,size(endmembers_hist,2));
                            fun     = @(rate) objectivefcn(rate, Y_hist, endmembers_hist, bins, app.realfit_mode);
                            aeq     = ones(1,size(endmembers_hist,2)); 
                            beq     = 1;%x1+x2+...=1
                            lb      = zeros(1,size(endmembers_hist,2)); %lower limit
                            ub      = ones(1,size(endmembers_hist,2)); %upperlimit
                            switch app.RF_Showiteration.Checked
                                case 'on'
                                    options = optimoptions('fmincon','MaxIterations',max_iterations,'OptimalityTolerance',conv_prec,'PlotFcn','optimplotfval','Display','iter');%plot optimization
                                case 'off'
                                    options = optimoptions('fmincon','MaxIterations',max_iterations,'OptimalityTolerance',conv_prec,'PlotFcn',[],'Display','off');%plot optimization
                            end
                    end

                    %fitting
                    [x, fval, exitflag, output] = fmincon(fun, x0, [],[], aeq, beq, lb, ub, [], options);
                    X = abs(x./(sum(x)));
                    contribution = X';

                    %write results
                    if exitflag >=1
                        convergence = repmat("Convergent",size(endmembers, 2),1);
                    else
                        convergence = repmat("Non-convergent",size(endmembers, 2),1);
                    end
                    objval = repmat(fval,size(endmembers,2),1);
                    unit = repmat(string(app.scale),size(endmembers, 2),1);

                    switch app.EvaluatewithKStestMenu.Checked
                        case 'on'
                            %KS Test
                            Num_samples = numel(Y);
                            em_r = round(Num_samples * X);
                            em_r(end) = Num_samples - sum(em_r(1:end-1));
        
                            Y_fit_rand = zeros(Num_samples,1);
                            em_s = 1;
                            for ei = 1:size(X,2)
                                endmember = endmembers(:,ei);
                                endmember = endmember(find(~isnan(endmember)));
                                rnidx = randi(numel(endmember), em_r(ei),1);
                                Y_fit_rand(em_s:em_s - 1 + em_r(ei)) = endmember(rnidx);
                                em_s = em_s + em_r(ei);
                            end
                            
                            [h, ~] = kstest2(Y, Y_fit_rand,'Alpha',0.05);
                            if h==1
                                evaluation = repmat("KS test: reject H0",size(endmembers, 2),1);
                            else
                                evaluation = repmat("KS test: fail to reject H0",size(endmembers, 2),1);
                            end
                            app.RF_data.T         = table(member_name, contribution, unit, mu, sigma, MEAN, sqrt(VAR), objval, convergence, evaluation);
                            app.RF_data.T.Properties.VariableNames = {'Name','Contribution', 'Unit', 'Dist_mu', 'Dist_sigma', 'Data_mean', 'Data_std', app.realfit_mode, 'Fit_Convergence', 'Fit_Evaluation'};
                  
                        case 'off'
                            app.RF_data.T         = table(member_name, contribution, unit, mu, sigma, MEAN, sqrt(VAR), objval, convergence);
                            app.RF_data.T.Properties.VariableNames = {'Name','Contribution', 'Unit', 'Dist_mu', 'Dist_sigma', 'Data_mean', 'Data_std', app.realfit_mode, 'Fit_Convergence'};
                    end
                    
                    app.RF_data.Y_raw     = Y;
                    app.RF_data.Y_hist    = Y_hist;
                    app.RF_data.X_bins    = bins;
                    app.RF_data.Z_hist    = endmembers_hist;
                    app.RF_data.random_idx= r_idx; 
                    %app.RF_data.Precision = fval;
                    app.RF_data.xname     = strcat(app.Analysecolumn.Value,'(',app.scale,')');
                    switch app.Ytype.Value
                        case 'Count'
                            app.RF_data.yname = strcat('Probability (Counts) [%]'); %app.RF_data.yname = strcat('Probability [%] (N=',num2str(size(Y,1)),')');
                        case 'Area'
                            app.RF_data.yname = strcat('Probability (Area) [%]');
                        case 'SEVolume'
                            app.RF_data.yname = strcat('Probability (SE Volume) [%]');
                    end
                    
                    %update table
                    app.table_type.Value = 'Real fit';
                    app.UITable.Data = [];
                    app.UITable.Data = app.RF_data.T;
                    app.UITable.ColumnName = app.RF_data.T.Properties.VariableNames;        
            end
            %real fit function
            %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
            function objval = objectivefcn(mixrate, data1, data2, bins, type)
                %mixrate: mixture rate
                %data1: target y axis value
                %data2: endmembers y axis values
                %type: function type
                switch type
                    case 'rmse'
                        M = [];
                        for em=1:size(data2,2) 
                            m = abs(mixrate(em)) * data2(:,em);
                            M = [M, m];
                        end
                        M = sum(M,2);
                        M = M/sum(M,1);

                        objval = sqrt(mean((data1 - M).^2));
                        %ssr = sum((data1- M).^2);
                    case 'logrmse'
                        M = [];
                        for em=1:size(data2,2) 
                            m = abs(mixrate(em)) * data2(:,em);
                            M = [M, m];
                        end
                        M = sum(M,2);
                        M = M/sum(M,1);
                        
                        objval = sqrt(sum((log(data1+1)- log(M+1)).^2)/length(data1));
                    case 'KDE'
                        %Kernel Density Estimation, 
                        BIN = bins(1:end-1) + mean(diff(bins))/2;

                        %KDE of target
                        [targetPDF, targetX] = ksdensity(data1, BIN);
                        
                        %KDE of endmembers
                        num_endmembers = size(data2, 2);
                        mixturePDF = zeros(length(BIN), 1);
                        for i = 1:num_endmembers
                            [f, ~] = ksdensity(data2(:, i), BIN);
                            mixturePDF = mixturePDF + f' * mixrate(i);
                        end
                        %calc probability
                        mixturePDF = mixturePDF / sum(mixturePDF, 1);
                        targetPDF  = targetPDF' / sum(targetPDF,  2); 

                        
                        %calc RMSE
                        objval = sqrt(mean((targetPDF - mixturePDF).^2));
                        
                    case 'bic'
                        mixrate = mixrate/sum(mixrate);
                        for em=1:size(data2,2) 
                            data2(:,em) = data2(:,em) * mixrate(em);
                        end

                        mus = mean(data2,1)';
                        sigmas = std(data2,1)';
                        n = size(data1, 1);
                        logL = 0;
                        m = length(mixrate);%num compornent

                        for v = 1:n
                            p = 0;
                            for j = 1:m
                                p = p + mixrate(j) * gaussianPDF(data1(v,:), mus(j), sigmas(j));
                            end
                            logL = logL + log(p);
                        end

                        % calc BIC
                        d = size(data1, 2);
                        k = m * (d + (d * (d + 1) / 2) + 1) - 1;
                        objval = -2 * logL + k * log(n);

                    case 'aic'
                        mixrate = mixrate/sum(mixrate);
                        for em=1:size(data2,2) 
                            data2(:,em) = data2(:,em) * mixrate(em);
                        end
                        
                        mus = mean(data2,1)';
                        sigmas = std(data2,1)';
                        n = size(data1, 1);
                        logL = 0;
                        m = length(mixrate);%num compornent

                        for v = 1:n
                            p = 0;
                            for j = 1:m
                                p = p + mixrate(j) * gaussianPDF(data1(v,:), mus(j), sigmas(j));
                            end
                            logL = logL + log(p);
                        end

                        % calc AIC
                        d = size(data1, 2);
                        k = m * (d + (d * (d + 1) / 2) + 1) - 1;  % パラメータ数
                        objval = -2 * logL + 2 * k;
                end
            end
            function p = gaussianPDF(x, mu, sigma)
                d = length(mu);
                normFactor = 1 / sqrt((2*pi)^d * det(sigma));
                diff = x - mu;
                exponent = -0.5 * diff * inv(sigma) * diff';
                p = normFactor * exp(exponent);
            end
            function [x, ecdf] = calcECDF(data)
                sortedData = sort(data);
                n = length(data);
                ecdf = (1:n)' / n;
                x = sortedData;
            end
            %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
           
            %plot
            app.plotResult(app.MethodDropDown.Value,'Liner');
            
        end

        % Callback function
        function HistogrambinsMenuSelected(app, event)
            prompt = {'Min of plot range:','Step of plot:','Max of plot range:'};
            dlgtitle = 'Please input plot range';
            dims = [1 35];
            definput = {num2str(app.plot_range(1)),num2str(app.plot_range(2)),num2str(app.plot_range(3))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.plot_range = [str2double(answer{1}),str2double(answer{2}),str2double(answer{3})];
        end

        % Callback function
        function ConvergenceprecisionMenuSelected(app, event)
            prompt = {'Fitting convergence precision:'};
            dlgtitle = 'Please input convergence presition';
            dims = [1 35];
            definput = {num2str(app.conv_prec)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.conv_prec = str2double(answer{1});
        end

        % Callback function
        function RandomsamplingMenuSelected(app, event)
            switch app.RandomsamplingMenu.Checked
                case 'on'
                    app.RandomsamplingMenu.Checked = 'off';
                case 'off'
                    app.RandomsamplingMenu.Checked = 'on';
                    prompt = {'Number of random sampling:'};
                    dlgtitle = 'Please input sampling saize';
                    dims = [1 35];
                    definput = {num2str(app.N_random)};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    app.N_random = str2double(answer{1});
            end
            app.plotResult('', app.scale)
        end

        % Menu selected function: CumulativecurveMenu
        function CumulativecurveMenuSelected(app, event)
            switch app.CumulativecurveMenu.Checked
                case 'on'
                    app.CumulativecurveMenu.Checked = 'off';
                    app.plotResult('', app.scale)

                case 'off'
                    app.CumulativecurveMenu.Checked = 'on';
                    app.plotResult('', app.scale)
            end
        end

        % Value changed function: table_type
        function table_typeValueChanged(app, event)
            value = app.table_type.Value;
            switch value
                case 'Original'
                    if isempty(app.raw_data)==1
                        return
                    end
                    app.UITable.Data = [];
                    app.UITable.Data = app.raw_data;
                    app.UITable.ColumnName = app.raw_data.Properties.VariableNames;
                    app.plotResult('',app.scale)
                case 'Gaussian mixture model'
                    if isempty(app.GM_data) ==1
                        return
                    end
                    app.UITable.Data = [];
                    app.UITable.Data = app.GM_data.T;
                    app.UITable.ColumnName = app.GM_data.T.Properties.VariableNames;
                    app.plotResult('Gaussian mixture model', app.scale)
                case 'Real fit'
                    if isempty(app.RF_data)==1
                        return
                    end
                    app.UITable.Data = [];
                    app.UITable.Data = app.RF_data.T;
                    app.UITable.ColumnName = app.RF_data.T.Properties.VariableNames;
                    app.plotResult('Real fit',app.scale)
                case 'Real fit endmembers'
                    if isempty(app.raw_endmembers)==1
                        return
                    end
                    app.UITable.Data = [];
                    app.UITable.Data = app.raw_endmembers;
                    app.UITable.ColumnName = app.raw_endmembers.Properties.VariableNames;
                    if ~isempty(app.RF_data)
                        app.plotResult('Real fit',app.scale)
                    end
                case 'Histogram data'
                    %initiarize
                    temp_Ytype = app.Ytype.Value;
                    results = [];

                    %get bindata
                    bins = [app.bins(1):app.bins(2):app.bins(3)]';
                    results = table(bins(1:end-1), bins(2:end));
                    results.Properties.VariableNames = {'Bin_lower', 'Bin_upper'};

                    %calc histogram data
                    app.Ytype.Value = 'Count'
                    [r_idx, Yweighted, Y_hist, xname, yname ] = app.makeHistData();
                    t = table(Y_hist);
                    t.Properties.VariableNames = {'Counts_Prob'};
                    results = [results, t];

                    switch app.EnableAreaMenu.Checked
                        case 'on'
                            cidx = cellfind_ra(app.raw_data.Properties.VariableNames,'AREA',0);
                            if ~isempty(cidx)
                                app.Ytype.Value = 'Area'
                                [r_idx, Yweighted, Y_hist, xname, yname ] = app.makeHistData();
                                t = table(Y_hist);
                                t.Properties.VariableNames = {'Area_Prob'};
                                results = [results, t];
                            end
                    end
                    
                    switch app.EnableAreaMenu.Checked
                        case 'on'
                            cidx = cellfind_ra(app.raw_data.Properties.VariableNames,'SEVolume',0);
                            if ~isempty(cidx)
                                app.Ytype.Value = 'SEVolume'
                                [r_idx, Yweighted, Y_hist, xname, yname ] = app.makeHistData();
                                t = table(Y_hist);
                                t.Properties.VariableNames = {'SEVolume_Prob'};
                                results = [results, t];
                            end
                    end

                    app.UITable.Data = results;
                    app.UITable.ColumnName = results.Properties.VariableNames;                     

            end

        end

        % Callback function
        function LinerMenuSelected(app, event)
            app.LinerMenu.Checked = 'on';
            app.LogMenu.Checked   = 'off';
            app.PhiMenu.Checked   = 'off';
            app.scale = 'Liner';
        end

        % Callback function
        function LogMenuSelected(app, event)
            app.LinerMenu.Checked = 'off';
            app.LogMenu.Checked   = 'on';
            app.PhiMenu.Checked   = 'off';
            app.scale = 'Ln';
        end

        % Callback function
        function PhiMenuSelected(app, event)
            app.LinerMenu.Checked = 'off';
            app.LogMenu.Checked   = 'off';
            app.PhiMenu.Checked   = 'on';
            app.scale = 'Phi';
        end

        % Callback function
        function MaxiterationMenuSelected(app, event)
            prompt = {'Fitting max iteration:'};
            dlgtitle = 'Please input max iteration';
            dims = [1 35];
            definput = {num2str(app.max_iteration)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.max_iteration = str2double(answer{1});
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value
            switch value 
                case 'Gaussian mixture model'
                    app.Numberofclass.Enable = 1;
                    app.Loadendembers.Enable = 0;
                case 'Real fit'
                    app.Numberofclass.Enable = 0;
                    app.Loadendembers.Enable = 1;
                case 'Real fit (N Prob)'
                    app.Numberofclass.Enable = 0;
                    app.Loadendembers.Enable = 1;
            end
        end

        % Menu selected function: OriginaldataMenu
        function OriginaldataMenuSelected(app, event)
            if isempty(app.raw_data)==1
                return
            end
            app.table_type.Value = 'Original';
            app.table_typeValueChanged();
        end

        % Menu selected function: GMresultMenu
        function GMresultMenuSelected(app, event)
            if isempty(app.GM_data)==1
                return
            end
            app.table_type.Value = 'Gaussian mixture model';
            app.table_typeValueChanged();
        end

        % Menu selected function: RealfitresultMenu
        function RealfitresultMenuSelected(app, event)
            if isempty(app.RF_data)==1
                return
            end
            app.table_type.Value = 'Real fit';
            app.table_typeValueChanged();
        end

        % Menu selected function: RealendmembersMenu
        function RealendmembersMenuSelected(app, event)
            if isempty(app.raw_endmembers)==1
                return
            end
            app.table_type.Value = 'Real fit endmembers';
            app.table_typeValueChanged();
        end

        % Menu selected function: HistogramdataMenu
        function HistogramdataMenuSelected(app, event)
            if isempty(app.raw_data)==1
                return
            end
            app.table_type.Value = 'Histogram data';
            app.table_typeValueChanged();
        end

        % Callback function
        function LoadendembersButtonPushed(app, event)
            %read single endmember file
            %get path
            [data_name, data_path] = uigetfile(fullfile(app.data_path, '*.csv'));
            if data_name==0
                return
            end
            %read csv
            T = readtable(fullfile(data_path, data_name));
            
            %get name
            [~,name,~] = fileparts(fullfile(app.data_path, data_name));
            
            %show result
            app.raw_endmembers = T;
            app.UITable.Data = [];
            app.UITable.Data = T;
            app.UITable.ColumnName = T.Properties.VariableNames;
            
            %update list
            app.table_type.Value = 'Real fit';

        end

        % Callback function
        function MaxiterationMenu_2Selected(app, event)
            prompt = {'Max iteration of analysis:'};
            dlgtitle = 'Please input max iteration';
            dims = [1 35];
            definput = {num2str(app.RF_max_iteration(1))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.RF_max_iteration = [str2double(answer)];
        end

        % Callback function
        function RMSEthresholdMenuSelected(app, event)
            prompt = {'Analysis RMSE (precision)'};
            dlgtitle = 'Please input RMSE of analysis';
            dims = [1 35];
            definput = {num2str(app.RF_threshold)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.RF_threshold = [str2double(answer)];
        end

        % Callback function
        function AnalysisbinsMenuSelected(app, event)
            prompt = {'Min of analysis:','Step of anaysis:','Max of analysis:'};
            dlgtitle = 'Please input analysis range';
            dims = [1 35];
            definput = {num2str(app.RF_bins(1)),num2str(app.RF_bins(2)),num2str(app.RF_bins(3))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.RF_bins = [str2double(answer{1}),str2double(answer{2}),str2double(answer{3})];
        end

        % Value changed function: Analysecolumn
        function AnalysecolumnValueChanged(app, event)
            switch app.AutorescaleMenu.Checked
                case 'on'
                   app.AutoscalingButtonPushed();
            end
            app.clearCalcedData();
            app.plotResult('',app.scale);
        end

        % Callback function
        function BinsMenuSelected(app, event)
            prompt   = {'Min of analysis:','Step of anaysis:','Max of analysis:'};
            dlgtitle = 'Please input analysis range';
            dims     = [1 35];
            definput = {num2str(app.bins(1)),num2str(app.bins(2)),num2str(app.bins(3))};
            answer   = inputdlg(prompt,dlgtitle,dims,definput);
            app.bins = [str2double(answer{1}),str2double(answer{2}),str2double(answer{3})];
        end

        % Menu selected function: MaxiterationMenu_3
        function MaxiterationMenu_3Selected(app, event)
            prompt   = {'Max iteration of analysis:'};
            dlgtitle = 'Please input max iteration';
            dims     = [1 35];
            definput = {num2str(app.max_iteration(1))};
            answer   = inputdlg(prompt, dlgtitle, dims,definput);
            app.max_iteration = [str2double(answer)];
        end

        % Menu selected function: ConvergencethresholdMenu
        function ConvergencethresholdMenuSelected(app, event)
            prompt   = {'Fitting convergence precision:'};
            dlgtitle = 'Please input convergence presition';
            dims     = [1 35];
            definput = {num2str(app.conv_prec)};
            answer   = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                return
            end
            app.conv_prec = str2double(answer{1});
        end

        % Menu selected function: RandomsamplingMenu
        function RandomsamplingMenuSelected2(app, event)
            switch app.RandomsamplingMenu.Checked
                case 'on'
                    app.RandomsamplingMenu.Checked = 'off';
                case 'off'
                    app.RandomsamplingMenu.Checked = 'on';
                    prompt = {'Number of random sampling:'};
                    dlgtitle = 'Please input sampling saize';
                    dims = [1 35];
                    definput = {num2str(app.N_random)};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    app.N_random = str2double(answer{1});
            end
        end

        % Value changed function: scaleDropDown
        function scaleDropDownValueChanged(app, event)
            app.scale = app.scaleDropDown.Value;
            switch app.AutorescaleMenu.Checked
                case 'on'
                    app.AutoscalingButtonPushed();
            end
            app.clearCalcedData();
            app.GM_data
            app.plotResult('',app.scale);
        end

        % Value changed function: B_min
        function B_minValueChanged(app, event)
            app.bins(1) = app.B_min.Value;
            app.plotResult('',app.scale);
        end

        % Value changed function: B_step
        function B_stepValueChanged(app, event)
            app.bins(2) = app.B_step.Value;
            app.plotResult('',app.scale);
        end

        % Value changed function: B_max
        function B_maxValueChanged(app, event)
            app.bins(3) = app.B_max.Value;
            app.plotResult('',app.scale);
        end

        % Menu selected function: STDMenu
        function STDMenuSelected(app, event)
            switch app.STDMenu.Checked
                case 'on'
                    app.STDMenu.Checked = 'off';
                    app.plotResult('',app.scale);
                case 'off'
                    app.STDMenu.Checked = 'on';
                    app.plotResult('',app.scale);
            end
        end

        % Menu selected function: StatisticsinfoMenu
        function StatisticsinfoMenuSelected(app, event)
            switch app.StatisticsinfoMenu.Checked
                case 'on'
                    app.StatisticsinfoMenu.Checked = 'off';
                    app.plotResult('',app.scale)
                case 'off'
                    app.StatisticsinfoMenu.Checked = 'on';
                    app.plotResult('',app.scale)
            end
        end

        % Button pushed function: AutoscalingButton
        function AutoscalingButtonPushed(app, event)
            if isempty(app.raw_data)
                return
            end
            
            T     = app.raw_data;
            Y     = app.convScale(T.(app.Analysecolumn.Value),app.scale);
            Y_min = round(min(Y),2);
            Y_max = round(max(Y),2);
            
            app.B_min.Value = Y_min;
            app.B_max.Value = Y_max;
            app.bins(1)     = Y_min;
            app.bins(3)     = Y_max;

            switch app.scale
                case 'Gaussian mixture model'
                    Y_hist = app.GM_data.Y_hist;
                case {'Real fit','Real fit (N Prob)'}
                    Y_hist = app.RF_data.Y_hist;
                otherwise
                    [r_idx, Y_raw,Y_hist, xname, yname ]  = app.makeHistData();
            end
             
            %convert Y axis to %
            Y_hist = Y_hist*100;
            
            app.Y_max.Value = ceil(max(Y_hist));

            app.plotResult('',app.scale);
        end

        % Menu selected function: GM_Showiteration
        function GM_ShowiterationMenuSelected(app, event)
            switch app.GM_Showiteration.Checked
                case 'on'
                    app.GM_Showiteration.Checked = 'off';
                case 'off'
                    app.GM_Showiteration.Checked = 'on';
            end
        end

        % Menu selected function: RF_Showiteration
        function RF_ShowiterationMenuSelected(app, event)
            switch app.RF_Showiteration.Checked
                case 'on'
                    app.RF_Showiteration.Checked = 'off';
                case 'off'
                    app.RF_Showiteration.Checked = 'on';
            end
        end

        % Value changed function: Ytype
        function YtypeValueChanged(app, event)
            yt = app.Ytype.Value;
            switch yt
                case 'Count'
                    switch app.AutorescaleMenu.Checked
                        case 'on'
                            app.AutoscalingButtonPushed();
                    end
                case 'Area'
                    C = cellfind_ra(app.raw_data.Properties.VariableNames,'AREA',0);
                    if isempty(C)==1
                        app.Ytype.Value = 'Count';
                    end
                    switch app.AutorescaleMenu.Checked
                        case 'on'
                            app.AutoscalingButtonPushed();
                    end
                case 'SEVolume'
                    C = cellfind_ra(app.raw_data.Properties.VariableNames,'SEVolume',0);
                    if isempty(C)
                        app.Ytype.Value = 'Count';
                    end
                    switch app.AutorescaleMenu.Checked
                        case 'on'
                            app.AutoscalingButtonPushed();
                    end
            end

            app.clearCalcedData();
            app.plotResult('',app.scale)
        end

        % Menu selected function: VersionMenu
        function VersionMenuSelected(app, event)
            uialert(app.RanalyserUIFigure,strcat('Version:',app.version),'Warning','Icon','info');
        end

        % Menu selected function: CalcSEvolumeMenu
        function CalcSEvolumeMenuSelected(app, event)
            switch app.CalcSEvolumeMenu.Checked
                case 'on'
                    app.CalcSEvolumeMenu.Checked='off';
                case 'off'
                    app.CalcSEvolumeMenu.Checked='on';
            end
        end

        % Callback function
        function KS1testMenuSelected(app, event)
            %set params
            app.MethodDropDown.Value = 'Gaussian mixture model';
            app.Numberofclass.Value = 1;
            
            %calc fit
            app.AnalyseButtonPushed(app);

            %calc ks test
            Yweighted = app.GM_data.Y_raw;
            mu = app.GM_data.T.Dist_mu(1);
            sigma = app.GM_data.T.Dist_sigma(1);

            test_data = (Yweighted-mu)/sigma;

            [ks,p,ksstat,cv] = kstest(test_data);
            if ks==1
                st = "Data is NON-normal distribution at the 5% significance.";
            else
                st = "This is normal distribution at the 5% significance.";
            end

            %show CDF
            figure
            cdfplot(test_data)
            title("")
            hold on
            x_values = linspace(min(test_data),max(test_data));
            plot(x_values, normcdf(x_values,0,1),'r-')
            legend('Empirical CDF','Standard Normal CDF','Location','best')

            %show info
            uiconfirm(app.RanalyserUIFigure, st,'1 sample KS test','Options',{'OK'},'Icon','info');

        end

        % Callback function
        function MWU2testMenuSelected(app, event)
            list = app.raw_data.Properties.VariableNames;

            [tid,tf] = listdlg('PromptString',{'Please select two pieces of data to Mann-Whitney U test.',''},'ListString',list,'SelectionMode','multiple');
            if length(tid)~=2
                uialert(app.RanalyserUIFigure,'Please select two pieces of data.','Warning','Icon','warning');
                return
            end

            x = app.raw_data.(list{tid(1)});
            y = app.raw_data.(list{tid(2)});

            [p,h,stats] = ranksum(x,y);

            if h==0
                res = 'Data have the same distribution at the 5% significance.';
            else
                res = 'Data have the DIFFERENT distribution at the 5% significance.';
            end

            figure
            cdfplot(x)
            hold on
            cdfplot(y)
            legend(list{tid(1)},list{tid(2)});
            title('Mann-Whitney U test')

            uiconfirm(app.RanalyserUIFigure, res,'2 sample MWU test','Options',{'OK'},'Icon','info');
        end

        % Menu selected function: EnableAreaMenu
        function EnableAreaMenuSelected(app, event)
            switch app.EnableAreaMenu.Checked
                case 'on'
                    app.EnableAreaMenu.Checked = 'off';
                    app.Ytype.Items = {'Count'};
                case 'off'
                    app.EnableAreaMenu.Checked = 'on';
                    app.Ytype.Items = {'Count','Area','SEVolume'};
            end

        end

        % Value changed function: Y_max
        function Y_maxValueChanged(app, event)
            value = app.Y_max.Value;
            app.plotResult('',app.scale);
        end

        % Value changed function: Y_min
        function Y_minValueChanged(app, event)
            value = app.Y_min.Value;
            app.plotResult('',app.scale);
        end

        % Menu selected function: FitmethodMenu
        function FitmethodMenuSelected(app, event)
            list = {'rmse','logrmse','aic','bic','KDE'};
            [tid,tf] = listdlg('PromptString',{'Please chose objecttive function method.',''},'ListString',list,'SelectionMode','single');
    
            if ~isempty(tid)
                app.realfit_mode = list{tid};
            end

        end

        % Menu selected function: KerneldensityMenu
        function KerneldensityMenuSelected(app, event)
            switch app.KerneldensityMenu.Checked
                case 'on'
                    app.KerneldensityMenu.Checked='off';
                    app.plotResult('',app.scale)
                case 'off'
                    app.KerneldensityMenu.Checked='on';
                    app.plotResult('',app.scale)
            end
        end

        % Menu selected function: AutorescaleMenu
        function AutorescaleMenuSelected(app, event)
            switch app.AutorescaleMenu.Checked
                case 'on'
                    app.AutorescaleMenu.Checked = 'off';
                    %app.AutoscalingButton.Enable = "off";
                case 'off'
                    app.AutorescaleMenu.Checked='on';
                    %app.AutoscalingButton.Enable = "on";
            end
        end

        % Menu selected function: GMMoptimizationMenu
        function GMMoptimizationMenuSelected(app, event)
            app.MethodDropDown.Value = "Gaussian mixture model";
            results = [];
            d = uiprogressdlg(app.RanalyserUIFigure,'Title','Please Wait','Message','Calculating...');
            for N = 1:10
                try
                    app.Numberofclass.Value = N;
                    app.AnalyseButtonPushed();
                    disp(app.GM_data.T)
                    if(isempty(app.GM_data.T))
                        return
                    end
                    T   = app.GM_data.T;
                    AIC = T.AIC(1);
                    BIC = T.BIC(1);
                    results = [results; table(N, AIC, BIC)];
                    
                catch ME
                    AIC = NaN;
                    BIC = NaN;
                    results = [results; table(N, AIC, BIC)]
                end 
                d.Value = N/10;
            end
            
            if~exist('T','var') || isempty(T)
                return
            end

            %show results
            [~, idx] = min(results{:,'AIC'});
            app.Numberofclass.Value = results{idx,'N'};
            app.AnalyseButtonPushed();

            figure
            yyaxis left
                plot(results{:,'N'}, results{:,'AIC'},'-*')
                xlabel('Number of components')
                ylabel('AIC')
                grid on
            yyaxis right
                plot(results{:,'N'}, results{:,'BIC'},'-o')
                ylabel('BIC')
                
            close(d)
        end

        % Menu selected function: EvaluatewithKStestMenu
        function EvaluatewithKStestMenuSelected(app, event)
            switch app.EvaluatewithKStestMenu.Checked
                case 'on'
                    app.EvaluatewithKStestMenu.Checked = 'off';
                    %app.AutoscalingButton.Enable = "off";
                case 'off'
                    app.EvaluatewithKStestMenu.Checked='on';
                    %app.AutoscalingButton.Enable = "on";
            end
        end

        % Button pushed function: ALoad
        function ALoadButtonPushed(app, event)
            app.ALabel.Text = "";
            app.Label_2.Value = "";
            app.compA = [];
            %get path
            [A_name, A_path] = uigetfile(fullfile(app.data_path, '*.csv'));
            if A_name==0
                return
            else
                app.data_path = A_path;
            end
            app.ALabel.Text = A_name;
            
            %read csv
            A  = readtable(fullfile(app.data_path, A_name));
            A.Properties.VariableNames = upper(A.Properties.VariableNames);
            app.compA = A;

            %update list
            app.AColumn.Items = A.Properties.VariableNames;
            app.plotComparisonData();

            dataA = app.getNeutralData(app.compA, app.AColumn.Value, 0);
            dataB = app.getNeutralData(app.compB, app.BColumn.Value, 0);

            numA = length(dataA);
            numB = length(dataB);

            if app.ResampleC.Value == 1
                dataA = app.getNeutralData(app.compA, app.AColumn.Value, app.minNum.Value);
                dataB = app.getNeutralData(app.compB, app.BColumn.Value, app.minNum.Value);
            end

            %display
            app.NALabel.Text = strcat("N=", num2str(numA));
            app.NBLabel.Text = strcat("N=", num2str(numB));
            if length(dataB)>0
                app.minNum.Value = min(length(dataA), length(dataB));
            else
                app.minNum.Value = length(dataA);
            end

        end

        % Button pushed function: BLoad
        function BLoadButtonPushed(app, event)
            app.BLabel.Text = "";
            app.Label_2.Value = "";
            app.compB = [];
            %get path
            [B_name, B_path] = uigetfile(fullfile(app.data_path, '*.csv'));
            if B_name==0
                return
            else
                app.data_path = B_path;
            end
            app.BLabel.Text = B_name;
            
            %read csv
            B  = readtable(fullfile(app.data_path, B_name));
            B.Properties.VariableNames = upper(B.Properties.VariableNames);
            app.compB = B;

            %update list
            app.BColumn.Items = B.Properties.VariableNames;
            app.plotComparisonData();

            dataA = app.getNeutralData(app.compA, app.AColumn.Value, 0);
            dataB = app.getNeutralData(app.compB, app.BColumn.Value, 0);

            numA = length(dataA);
            numB = length(dataB);

            if app.ResampleC.Value == 1
                dataA = app.getNeutralData(app.compA, app.AColumn.Value, app.minNum.Value);
                dataB = app.getNeutralData(app.compB, app.BColumn.Value, app.minNum.Value);
            end

            %display
            app.NALabel.Text = strcat("N=", num2str(numA));
            app.NBLabel.Text = strcat("N=", num2str(numB));

            app.minNum.Value = min(length(dataA), length(dataB));
        end

        % Value changed function: AColumn
        function AColumnValueChanged(app, event)
            value = app.AColumn.Value;
            %display
            app.plotComparisonData();
            app.NALabel.Text = strcat("N=", num2str(length(app.getNeutralData(app.compA, app.AColumn.Value, 0))));
            app.NBLabel.Text = strcat("N=", num2str(length(app.getNeutralData(app.compB, app.BColumn.Value, 0))));            
            app.Label_2.Value = "";
        end

        % Value changed function: BColumn
        function BColumnValueChanged(app, event)
            value = app.BColumn.Value;
            %display
            app.plotComparisonData();
            app.NALabel.Text = strcat("N=", num2str(length(app.getNeutralData(app.compA, app.AColumn.Value, 0))));
            app.NBLabel.Text = strcat("N=", num2str(length(app.getNeutralData(app.compB, app.BColumn.Value, 0))));            
            app.Label_2.Value = "";
        end

        % Value changed function: TestMethod
        function TestMethodValueChanged(app, event)
            value = app.TestMethod.Value;
            app.Label_2.Value = "";
            switch value
                case "AD test"
                    %1 sample
                    app.BLoad.Visible = "off";
                    app.BColumn.Visible = "off";
                    app.NormaldistributionLabel.Visible = "on";
                    app.descriptionLabel.Text = "[non-parametric] Normality will be tested using the Anderson–Darling test( α = 0.05).";
                    app.minNum.Value = str2double(app.NALabel.Text(3:end));
                    %display
                    app.NALabel.Visible = "on";
                    app.NBLabel.Visible = "off";
                    app.ALabel.Visible  = "on";
                    app.BLabel.Visible  = "off";
                case "KS test"
                    % 2 samples
                    app.BLoad.Visible = "on";
                    app.BColumn.Visible = "on";
                    app.NormaldistributionLabel.Visible = "off";
                    app.descriptionLabel.Text = "[non-parametric] Differences between two distributions will be tested using the Kolmogorov–Smirnov test(α = 0.05).";
                    app.minNum.Value = min([str2double(app.NALabel.Text(3:end)), str2double(app.NBLabel.Text(3:end))]);
                    %display
                    app.NALabel.Visible = "on";
                    app.NBLabel.Visible = "on";
                    app.ALabel.Visible  = "on";
                    app.BLabel.Visible  = "on";      
                case "MWU test"
                    % 2 samples
                    app.BLoad.Visible = "on";
                    app.BColumn.Visible = "on";
                    app.NormaldistributionLabel.Visible = "off";
                    app.descriptionLabel.Text = "[non-parametric] Differences between two samples will be tested using the Mann–Whitney U test( α = 0.05).";
                    app.minNum.Value = min([str2double(app.NALabel.Text(3:end)), str2double(app.NBLabel.Text(3:end))]);

                    app.NALabel.Visible = "on";
                    app.NBLabel.Visible = "on";
                    app.ALabel.Visible  = "on";
                    app.BLabel.Visible  = "on";            
                case "CvM test"
                    % 2 samples
                    app.BLoad.Visible = "on";
                    app.BColumn.Visible = "on";
                    app.NormaldistributionLabel.Visible = "off";
                    app.descriptionLabel.Text = "[non-parametric] Differences between two distributions will be tested using the Cramér–von Mises test( α = 0.05).";
                    app.minNum.Value = min([str2double(app.NALabel.Text(3:end)), str2double(app.NBLabel.Text(3:end))]);

                    app.NALabel.Visible = "on";
                    app.NBLabel.Visible = "on";
                    app.ALabel.Visible  = "on";
                    app.BLabel.Visible  = "on";            
                case "MMD test"
                    % 2 samples
                    app.BLoad.Visible = "on";
                    app.BColumn.Visible = "on";
                    app.NormaldistributionLabel.Visible = "off";
                    app.descriptionLabel.Text = "[non-parametric] Differences between two distributions will be tested using the Maximum Mean Discrepancy (MMD) test (α = 0.05).";
                    app.minNum.Value = min([str2double(app.NALabel.Text(3:end)), str2double(app.NBLabel.Text(3:end))]);

                    app.NALabel.Visible = "on";
                    app.NBLabel.Visible = "on";
                    app.ALabel.Visible  = "on";
                    app.BLabel.Visible  = "on";            
                case "kNN test"
                    % 2 samples
                    app.BLoad.Visible = "on";
                    app.BColumn.Visible = "on";
                    app.NormaldistributionLabel.Visible = "off";
                    app.descriptionLabel.Text = "[non-parametric] Differences between two distributions will be tested using the k-nearest neighbors (kNN) test (α = 0.05).";
                    app.minNum.Value = min([str2double(app.NALabel.Text(3:end)), str2double(app.NBLabel.Text(3:end))]);

                    app.NALabel.Visible = "on";
                    app.NBLabel.Visible = "on";
                    app.ALabel.Visible  = "on";
                    app.BLabel.Visible  = "on";           
                otherwise
            end
            app.plotComparisonData();
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            %initiarise 
            app.compSizeTest = [];
            warnState = warning('off','stats:adtest:PTooSmall');            

            % get method
            testMethod = app.TestMethod.Value;

            % get initial dataset
            dataA = app.getNeutralData(app.compA, app.AColumn.Value, 0);
            dataB = app.getNeutralData(app.compB, app.BColumn.Value, 0);

            if app.ResampleC.Value == 1
                dataA = app.getNeutralData(app.compA, app.AColumn.Value, app.minNum.Value);
                dataB = app.getNeutralData(app.compB, app.BColumn.Value, app.minNum.Value);
            end

            %get sample saize
            numAllA = length(dataA);
            numAllB = length(dataB);
            if numAllB ==0
                numMinSample = numAllA;
            else
                numMinSample = min([numAllA, numAllB]);
            end
            numMaxSample = numMinSample;
            numRepeat = 1;
            if app.evalC.Value == 1
                numMaxSample = numMinSample;
                numMinSample = 4;%                
                numRepeat = 1000;                
            end

            if numMaxSample > 500
                step = -100;
            else
                step = -10;
            end

            % roop test
            results = [];
            dataAIdx = [];
            dataBIdx = [];
            d0 = uiprogressdlg(app.RanalyserUIFigure, 'Title','Now testing...');
            d0.Value = 0;
            cc=0;
            for i=numMaxSample:step:numMinSample
                cc=cc+1;
                d0.Value = (1 - i/round((max([length(dataA), length(dataB)]))));                

                res = [];
                for c=1:numRepeat
                    % update sampling    
                    Aidx = [];
                    Bidx = [];
                    if app.ResampleC.Value == 1                        
                        if app.evalC.Value == 1
                            [dataA, Aidx] = app.getNeutralData(app.compA, app.AColumn.Value, i);
                            [dataB, Bidx] = app.getNeutralData(app.compB, app.BColumn.Value, i);
                        else
                            [dataA, Aidx] = app.getNeutralData(app.compA, app.AColumn.Value, app.minNum.Value);
                            [dataB, Bidx] = app.getNeutralData(app.compB, app.BColumn.Value, app.minNum.Value);
                        end
                        
                    else
                        dataA = app.getNeutralData(app.compA, app.AColumn.Value, 0);
                        dataB = app.getNeutralData(app.compB, app.BColumn.Value, 0);
                    end

                    %test
                    switch testMethod
                        case "AD test"
                            %check data
                            if ~isnumeric(dataA) || numel(dataA) < 2
                                return
                            end

                            %test
                            alpha = 0.05;
                            lastwarn('');
                            warnState = warning('off','stats:adtest:OutOfRangePLow');
                            [h,p,stat,cv] = adtest(dataA,'Distribution','norm','Alpha',alpha);
                            [msg,id] = lastwarn;
                            warning(warnState);
                          

                            if p <= 0.0005
                                pDisp = "<0.0005";
                            else
                                pDisp = sprintf('%.4g', p);
                            end
        
                            result = table( ...
                                length(dataA), h, p, stat, cv, alpha, categorical(~h, [0 1], {'Reject normality','Fail to reject'}), ...
                                'VariableNames',{'num A', 'H','pValue','ADstat','CriticalValue','Alpha','Decision'} ...
                            );
                            
                            res = [res; result]; 
                        case "KS test"
                            %check data
                            if ~isnumeric(dataA) || numel(dataA) < 2 || ~isnumeric(dataB) || numel(dataB) < 2
                                return
                            end        

                            %test
                            alpha = 0.05;                        
                            [h,p,ksstat] = kstest2(dataA, dataB, 'Alpha', alpha);
                        
                            result = table( ...
                                length(dataA), length(dataB), h, p, ksstat, alpha, categorical(~h, [0 1], {'Reject equality','Fail to reject'}), ...
                                'VariableNames',{'num A', 'num B', 'H','pValue','KSstat','Alpha','Decision'} ...
                            );
                                            
                            res = [res; result];
                        case "MWU test"                
                            % check data
                            if ~isnumeric(dataA) || numel(dataA) < 2 || ~isnumeric(dataB) || numel(dataB) < 2
                                return
                            end
                        
                            %test
                            alpha = 0.05;                        
                            [p,h,stats] = ranksum(dataA, dataB, 'Alpha', alpha);
                            U = stats.ranksum;   % Mann–Whitney U
                        
                            result = table( ...
                                length(dataA), length(dataB), h, p, U, alpha, categorical(~h, [0 1], {'Reject equality','Fail to reject'}), ...
                                'VariableNames',{'num A', 'num B', 'H','pValue','Ustat','Alpha','Decision'} ...
                            );
                                            
                            res = [res; result];
                        case "CvM test"
                            % check data
                            if ~isnumeric(dataA) || numel(dataA) < 2 || ~isnumeric(dataB) || numel(dataB) < 2
                                return
                            end
                        
                            %test
                            alpha = 0.05;
                            B = 5000;          % permutations
                            seed = 1;          % or []
                        
                            %logit
                            % dataA = dataA(dataA > 0 & dataA < 1);
                            % dataB = dataB(dataB > 0 & dataB < 1);
                            % nA = numel(dataA);
                            % nB = numel(dataB);
                            % dataA = (dataA*(nA-1) + 0.5) / nA;   % 0 < data_adj < 1
                            % dataB = (dataB*(nB-1) + 0.5) / nB;   % 0 < data_adj < 1
                            % dataA = log(dataA ./ (1 - dataA));
                            % dataB = log(dataB ./ (1 - dataB));
        
                            [Tobs, p, Tperm] = cvm2_perm(dataA, dataB, B, seed);
                            h = p < alpha;
                        
                            result = table( ...
                                length(dataA), length(dataB), h, p, Tobs, alpha, categorical(~h, [0 1], {'Reject equality','Fail to reject'}), ...
                                'VariableNames',{'num A', 'num B', 'H','pValue','CvMstat','Alpha','Decision'} ...
                            );
                        
                            res = [res; result];
                        case "kNN test"
                            % check data                        
                            if ~isnumeric(dataA) || numel(dataA) < 2 || ~isnumeric(dataB) || numel(dataB) < 2
                                return
                            end
                        
                            %test
                            alpha = 0.05;
                            X = dataA(:);
                            Y = dataB(:);
                            [knnstat,p,h] = knntest(X,Y,Alpha=alpha,NumNeighbors=10,Distance="euclidean");
                        
                            result = table( ...
                                length(dataA), length(dataB), h, p, knnstat, alpha, categorical(~h, [0 1], {'Reject equality','Fail to reject'}), ...
                                'VariableNames',{'num A', 'num B', 'H','pValue','KNNstat','Alpha','Decision'} ...
                            );
                                            
                            res = [res; result];
                        case "MMD test"
                            d = uiprogressdlg(app.RanalyserUIFigure, 'Title','Now testing...', 'Indeterminate','on');
                            % check data
                            if ~isnumeric(dataA) || numel(dataA) < 2 || ~isnumeric(dataB) || numel(dataB) < 2
                                return
                            end
                        
                            %test
                            alpha = 0.05;                        
                            X = dataA(:);   % n×1
                            Y = dataB(:);   % m×1                        
                            [mmdval,p,h] = mmdtest(X,Y,Alpha=alpha,NumPermutations=1000);
                        
                            result = table( ...
                                length(dataA), length(dataB), h, p, mmdval, alpha, categorical(~h, [0 1], {'Reject equality','Fail to reject'}), ...
                                'VariableNames',{'num A', 'num B', 'H','pValue','MMDstat','Alpha','Decision'} ...
                            );
                                            
                            res = [res; result];
                            close(d)                               
                    end     

                    %get result of max input data
                    if cc==1
                        results = result;
                        dataAIdx = Aidx;
                        daatBIdx = Bidx;
                    end

                end

                % calc eval values
                if app.evalC.Value
                    t = table(length(dataA),length(dataB), median(res{:,'pValue'}), std(res{:,'pValue'}), alpha);
                    t.Properties.VariableNames = ["num_sampleA","num_sampleB","p_median","p_std","alpha"];
                    app.compSizeTest = [app.compSizeTest; t]; 
                end
            end
            close(d0)

            %show result
            if(height(results))
                resText = strcat(string(categorical(~results{1,'H'}, [0 1], {'Reject equality','Fail to reject'})), " (p = ", num2str(p), ", α = ", num2str(alpha), ")");
                app.Label_2.Value = resText;
                if results{1,'H'}
                    app.Label_2.FontColor = "red";
                else
                    app.Label_2.FontColor = "blue";
                end
                disp(results)   
    
                %plot
                app.plotComparisonData(dataAIdx, dataBIdx);
                if results{1,'H'}
                    text(app.CompPlot,0.05, 0.90, string(categorical(~results{1,'H'}, [0 1], {'Reject equality','Fail to reject'})),Color="red")
                else
                    text(app.CompPlot,0.05, 0.90, string(categorical(~results{1,'H'}, [0 1], {'Reject equality','Fail to reject'})),Color="blue")
                end
                
                text(app.CompPlot,0.05, 0.85, strcat("p = ", num2str(p)))
                text(app.CompPlot,0.05, 0.80, strcat("α = ", num2str(alpha)))
                text(app.CompPlot,0.05, 0.75, strcat("A: N=",num2str(numAllA)))
                switch testMethod
                    case "AD test"
                    otherwise
                        text(app.CompPlot,0.05, 0.70, strcat("B: N=",num2str(numAllB)))
                end     
            end
                     
        end

        % Value changed function: ResampleC
        function ResampleCValueChanged(app, event)
            value = app.ResampleC.Value;
            app.Label_2.Value = "";

            dataA = app.getNeutralData(app.compA, app.AColumn.Value, 0);
            dataB = app.getNeutralData(app.compB, app.BColumn.Value, 0);

            numA = length(dataA);
            numB = length(dataB);

            if numB == 0
                numSample = numA;
            else
                numSample = min([numA, numB]);
            end
            app.minNum.Value = numSample;

            if value
                app.minNum.Visible = "on";
                app.evalC.Visible = "on";
            else
                app.minNum.Visible = "off";
                app.evalC.Visible = "off";
                app.eval.Visible = "off";
                cla(app.eval)
                app.evalC.Value = 0;
            end
        end

        % Value changed function: evalC
        function evalCValueChanged(app, event)
            value = app.evalC.Value;
            app.Label_2.Value = "";
            cla(app.eval);
            if value == 1
                app.eval.Visible = true;
            else
                app.eval.Visible = false;
            end
        end

        % Value changed function: minNum
        function minNumValueChanged(app, event)
            value = app.minNum.Value;
            app.Label_2.Value = "";

            dataA = app.getNeutralData(app.compA, app.AColumn.Value, 0);
            dataB = app.getNeutralData(app.compB, app.BColumn.Value, 0);

            numA = length(dataA);
            numB = length(dataB);

            switch app.TestMethod.Value
                case "AD test"
                    if value>numA
                        app.minNum.Value = numA;
                    end
                otherwise
                    if value>min([numA,numB])
                        app.minNum.Value = min([numA,numB]);
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RanalyserUIFigure = uifigure('Visible', 'off');
            app.RanalyserUIFigure.Name = 'Ranalyser';
            app.AdvancedsettingsMenu = uimenu(app.RanalyserUIFigure);
            app.AdvancedsettingsMenu.Text = 'Advanced settings';
            app.LoadMenu = uimenu(app.AdvancedsettingsMenu);
            app.LoadMenu.Text = 'Load';
            app.CalcSEvolumeMenu = uimenu(app.LoadMenu);
            app.CalcSEvolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @CalcSEvolumeMenuSelected, true);
            app.CalcSEvolumeMenu.Text = 'Calc SE volume';
            app.PlotMenu = uimenu(app.AdvancedsettingsMenu);
            app.PlotMenu.Text = 'Plot';
            app.CumulativecurveMenu = uimenu(app.PlotMenu);
            app.CumulativecurveMenu.MenuSelectedFcn = createCallbackFcn(app, @CumulativecurveMenuSelected, true);
            app.CumulativecurveMenu.Text = 'Cumulative curve';
            app.STDMenu = uimenu(app.PlotMenu);
            app.STDMenu.MenuSelectedFcn = createCallbackFcn(app, @STDMenuSelected, true);
            app.STDMenu.Checked = 'on';
            app.STDMenu.Text = 'STD';
            app.StatisticsinfoMenu = uimenu(app.PlotMenu);
            app.StatisticsinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @StatisticsinfoMenuSelected, true);
            app.StatisticsinfoMenu.Text = 'Statistics info';
            app.KerneldensityMenu = uimenu(app.PlotMenu);
            app.KerneldensityMenu.MenuSelectedFcn = createCallbackFcn(app, @KerneldensityMenuSelected, true);
            app.KerneldensityMenu.Text = 'Kernel density';
            app.AnalysissettingsMenu = uimenu(app.AdvancedsettingsMenu);
            app.AnalysissettingsMenu.Text = 'Analysis settings';
            app.ConvergencethresholdMenu = uimenu(app.AnalysissettingsMenu);
            app.ConvergencethresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvergencethresholdMenuSelected, true);
            app.ConvergencethresholdMenu.Text = 'Convergence threshold';
            app.MaxiterationMenu_3 = uimenu(app.AnalysissettingsMenu);
            app.MaxiterationMenu_3.MenuSelectedFcn = createCallbackFcn(app, @MaxiterationMenu_3Selected, true);
            app.MaxiterationMenu_3.Text = 'Max iteration';
            app.RandomsamplingMenu = uimenu(app.AnalysissettingsMenu);
            app.RandomsamplingMenu.MenuSelectedFcn = createCallbackFcn(app, @RandomsamplingMenuSelected2, true);
            app.RandomsamplingMenu.Text = 'Random sampling';
            app.EnableAreaMenu = uimenu(app.AnalysissettingsMenu);
            app.EnableAreaMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableAreaMenuSelected, true);
            app.EnableAreaMenu.Text = 'Enable Area';
            app.AutorescaleMenu = uimenu(app.AnalysissettingsMenu);
            app.AutorescaleMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorescaleMenuSelected, true);
            app.AutorescaleMenu.Text = 'Auto rescale';
            app.GaussianfitMenu = uimenu(app.AnalysissettingsMenu);
            app.GaussianfitMenu.Text = 'Gaussian fit';
            app.GM_Showiteration = uimenu(app.GaussianfitMenu);
            app.GM_Showiteration.MenuSelectedFcn = createCallbackFcn(app, @GM_ShowiterationMenuSelected, true);
            app.GM_Showiteration.Text = 'Show iteration';
            app.RealfitMenu = uimenu(app.AnalysissettingsMenu);
            app.RealfitMenu.Text = 'Real fit';
            app.RF_Showiteration = uimenu(app.RealfitMenu);
            app.RF_Showiteration.MenuSelectedFcn = createCallbackFcn(app, @RF_ShowiterationMenuSelected, true);
            app.RF_Showiteration.Text = 'Show iteration';
            app.FitmethodMenu = uimenu(app.RealfitMenu);
            app.FitmethodMenu.MenuSelectedFcn = createCallbackFcn(app, @FitmethodMenuSelected, true);
            app.FitmethodMenu.Text = 'Fit method';
            app.EvaluatewithKStestMenu = uimenu(app.AnalysissettingsMenu);
            app.EvaluatewithKStestMenu.MenuSelectedFcn = createCallbackFcn(app, @EvaluatewithKStestMenuSelected, true);
            app.EvaluatewithKStestMenu.Text = 'Evaluate with KS test';
            app.ToolMenu = uimenu(app.RanalyserUIFigure);
            app.ToolMenu.Text = 'Tool';
            app.GMMoptimizationMenu = uimenu(app.ToolMenu);
            app.GMMoptimizationMenu.MenuSelectedFcn = createCallbackFcn(app, @GMMoptimizationMenuSelected, true);
            app.GMMoptimizationMenu.Text = 'GMM optimization';
            app.infoMenu = uimenu(app.RanalyserUIFigure);
            app.infoMenu.Text = 'info';
            app.VersionMenu = uimenu(app.infoMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuSelected, true);
            app.VersionMenu.Text = 'Version';
            app.TabGroup = uitabgroup(app.RanalyserUIFigure);
            app.DistributionTab = uitab(app.TabGroup);
            app.DistributionTab.Title = 'Distribution';
            app.LeftPanel = uipanel(app.DistributionTab);
            app.LoadPanel = uipanel(app.LeftPanel);
            app.LoadPanel.Title = '0. Load';
            app.batch = uicheckbox(app.LoadPanel);
            app.batch.Text = 'read all in folder';
            app.LoadcsvdataButton = uibutton(app.LoadPanel, 'push');
            app.LoadcsvdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcsvdataButtonPushed, true);
            app.LoadcsvdataButton.Text = ' Load csv data';
            app.data_label = uilabel(app.LoadPanel);
            app.data_label.Text = 'No data';
            app.AnalysersettingsPanel = uipanel(app.LeftPanel);
            app.AnalysersettingsPanel.Title = '2. Analyser settings';
            app.AnalyseButton = uibutton(app.AnalysersettingsPanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.MethodDropDownLabel = uilabel(app.AnalysersettingsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.AnalysersettingsPanel);
            app.MethodDropDown.Items = {'Gaussian mixture model', 'Real fit', 'Real fit (N Prob)'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Gaussian mixture model';
            app.ComponentsLabel = uilabel(app.AnalysersettingsPanel);
            app.ComponentsLabel.Text = 'Components';
            app.Numberofclass = uieditfield(app.AnalysersettingsPanel, 'numeric');
            app.Numberofclass.Limits = [0 10];
            app.Numberofclass.ValueDisplayFormat = '%.0f';
            app.Loadendembers = uibutton(app.AnalysersettingsPanel, 'push');
            app.Loadendembers.Enable = 'off';
            app.Loadendembers.Text = 'Load endembers';
            app.ExportPanel = uipanel(app.LeftPanel);
            app.ExportPanel.Title = '3. Export';
            app.SavedataButton = uibutton(app.ExportPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.DisplayedtableCheckBox = uicheckbox(app.ExportPanel);
            app.DisplayedtableCheckBox.Text = 'Displayed table';
            app.DisplayedtableCheckBox.Value = true;
            app.DisplayedhistogramCheckBox = uicheckbox(app.ExportPanel);
            app.DisplayedhistogramCheckBox.Text = 'Displayed histogram';
            app.DisplayedhistogramCheckBox.Value = true;
            app.DataacquisitionPanel = uipanel(app.LeftPanel);
            app.DataacquisitionPanel.Title = '1. Data acquisition';
            app.MinLabel = uilabel(app.DataacquisitionPanel);
            app.MinLabel.Text = 'Min';
            app.Y_min = uieditfield(app.DataacquisitionPanel, 'numeric');
            app.Y_min.ValueChangedFcn = createCallbackFcn(app, @Y_minValueChanged, true);
            app.Y_max = uieditfield(app.DataacquisitionPanel, 'numeric');
            app.Y_max.ValueChangedFcn = createCallbackFcn(app, @Y_maxValueChanged, true);
            app.Y_max.Value = 10;
            app.B_min = uieditfield(app.DataacquisitionPanel, 'numeric');
            app.B_min.ValueChangedFcn = createCallbackFcn(app, @B_minValueChanged, true);
            app.B_step = uieditfield(app.DataacquisitionPanel, 'numeric');
            app.B_step.ValueChangedFcn = createCallbackFcn(app, @B_stepValueChanged, true);
            app.B_step.Value = 0.02;
            app.B_max = uieditfield(app.DataacquisitionPanel, 'numeric');
            app.B_max.ValueChangedFcn = createCallbackFcn(app, @B_maxValueChanged, true);
            app.B_max.Value = 1;
            app.StepLabel = uilabel(app.DataacquisitionPanel);
            app.StepLabel.Text = 'Step';
            app.MaxLabel = uilabel(app.DataacquisitionPanel);
            app.MaxLabel.Text = 'Max';
            app.ScaleLabel = uilabel(app.DataacquisitionPanel);
            app.ScaleLabel.Text = 'Scale';
            app.scaleDropDown = uidropdown(app.DataacquisitionPanel);
            app.scaleDropDown.Items = {'Liner', 'Ln', 'Phi'};
            app.scaleDropDown.ValueChangedFcn = createCallbackFcn(app, @scaleDropDownValueChanged, true);
            app.scaleDropDown.Value = 'Liner';
            app.AnalysecolmunLabel = uilabel(app.DataacquisitionPanel);
            app.AnalysecolmunLabel.Text = 'Target column';
            app.Analysecolumn = uidropdown(app.DataacquisitionPanel);
            app.Analysecolumn.Items = {'Roundness'};
            app.Analysecolumn.ValueChangedFcn = createCallbackFcn(app, @AnalysecolumnValueChanged, true);
            app.Analysecolumn.Value = 'Roundness';
            app.AutoscalingButton = uibutton(app.DataacquisitionPanel, 'push');
            app.AutoscalingButton.ButtonPushedFcn = createCallbackFcn(app, @AutoscalingButtonPushed, true);
            app.AutoscalingButton.Text = 'Auto scaling';
            app.XaxisbinsLabel = uilabel(app.DataacquisitionPanel);
            app.XaxisbinsLabel.Text = 'X axis (bins)';
            app.YaxisheightLabel = uilabel(app.DataacquisitionPanel);
            app.YaxisheightLabel.Text = 'Y axis (height)';
            app.CounttypeLabel = uilabel(app.DataacquisitionPanel);
            app.CounttypeLabel.Text = 'Count type';
            app.Ytype = uidropdown(app.DataacquisitionPanel);
            app.Ytype.Items = {'Count'};
            app.Ytype.ValueChangedFcn = createCallbackFcn(app, @YtypeValueChanged, true);
            app.Ytype.Value = 'Count';
            app.MinLabel_2 = uilabel(app.DataacquisitionPanel);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.DataacquisitionPanel);
            app.MaxLabel_2.Text = 'Max';
            app.RightPanel = uipanel(app.DistributionTab);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TabletypeLabel = uilabel(app.Panel);
            app.TabletypeLabel.Text = 'Table type';
            app.table_type = uidropdown(app.Panel);
            app.table_type.Items = {'Original', 'Gaussian mixture model', 'Real fit', 'Real fit endmembers', 'Histogram data'};
            app.table_type.ValueChangedFcn = createCallbackFcn(app, @table_typeValueChanged, true);
            app.table_type.Value = 'Original';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.CompPlot = uiaxes(app.ComparisonTab);
            xlabel(app.CompPlot, 'Data value')
            ylabel(app.CompPlot, 'Cumulative probability (%)')
            zlabel(app.CompPlot, 'Z')
            app.qqax = uiaxes(app.ComparisonTab);
            xlabel(app.qqax, 'Theoretical Quantiles')
            ylabel(app.qqax, 'Sample Quantiles')
            zlabel(app.qqax, 'Z')
            app.eval = uiaxes(app.ComparisonTab);
            xlabel(app.eval, 'Sample size')
            ylabel(app.eval, 'p value')
            zlabel(app.eval, 'Z')
            app.eval.Visible = 'off';
            app.MethodDropDown_2Label = uilabel(app.ComparisonTab);
            app.MethodDropDown_2Label.Text = 'Method';
            app.TestMethod = uidropdown(app.ComparisonTab);
            app.TestMethod.Items = {'AD test', 'KS test', 'MWU test', 'CvM test', 'kNN test', 'MMD test'};
            app.TestMethod.ValueChangedFcn = createCallbackFcn(app, @TestMethodValueChanged, true);
            app.TestMethod.Value = 'AD test';
            app.ALoad = uibutton(app.ComparisonTab, 'push');
            app.ALoad.ButtonPushedFcn = createCallbackFcn(app, @ALoadButtonPushed, true);
            app.ALoad.Text = 'Load';
            app.ALabel_2 = uilabel(app.ComparisonTab);
            app.ALabel_2.Text = 'A';
            app.BLoad = uibutton(app.ComparisonTab, 'push');
            app.BLoad.ButtonPushedFcn = createCallbackFcn(app, @BLoadButtonPushed, true);
            app.BLoad.Visible = 'off';
            app.BLoad.Text = 'Load';
            app.BLabel_2 = uilabel(app.ComparisonTab);
            app.BLabel_2.Text = 'B';
            app.AColumn = uidropdown(app.ComparisonTab);
            app.AColumn.ValueChangedFcn = createCallbackFcn(app, @AColumnValueChanged, true);
            app.BColumn = uidropdown(app.ComparisonTab);
            app.BColumn.ValueChangedFcn = createCallbackFcn(app, @BColumnValueChanged, true);
            app.BColumn.Visible = 'off';
            app.NormaldistributionLabel = uilabel(app.ComparisonTab);
            app.NormaldistributionLabel.Text = 'Normal distribution';
            app.TestButton = uibutton(app.ComparisonTab, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.descriptionLabel = uilabel(app.ComparisonTab);
            app.descriptionLabel.Text = '[non-parametric] Normality will be tested using the Anderson–Darling test( α = 0.05).';
            app.ALabel = uilabel(app.ComparisonTab);
            app.ALabel.Text = '';
            app.BLabel = uilabel(app.ComparisonTab);
            app.BLabel.Text = '';
            app.ResampleC = uicheckbox(app.ComparisonTab);
            app.ResampleC.ValueChangedFcn = createCallbackFcn(app, @ResampleCValueChanged, true);
            app.ResampleC.Text = 'Resample';
            app.minNum = uieditfield(app.ComparisonTab, 'numeric');
            app.minNum.Limits = [0 Inf];
            app.minNum.ValueChangedFcn = createCallbackFcn(app, @minNumValueChanged, true);
            app.minNum.Visible = 'off';
            app.evalC = uicheckbox(app.ComparisonTab);
            app.evalC.ValueChangedFcn = createCallbackFcn(app, @evalCValueChanged, true);
            app.evalC.Visible = 'off';
            app.evalC.Text = 'evaluate sample size';
            app.Label_2 = uieditfield(app.ComparisonTab, 'text');
            app.Label_2.Editable = 'off';
            app.NALabel = uilabel(app.ComparisonTab);
            app.NALabel.Text = '';
            app.NBLabel = uilabel(app.ComparisonTab);
            app.NBLabel.Text = '';
            app.ContextMenu = uicontextmenu(app.RanalyserUIFigure);
            app.OriginaldataMenu = uimenu(app.ContextMenu);
            app.OriginaldataMenu.MenuSelectedFcn = createCallbackFcn(app, @OriginaldataMenuSelected, true);
            app.OriginaldataMenu.Text = 'Original data';
            app.GMresultMenu = uimenu(app.ContextMenu);
            app.GMresultMenu.MenuSelectedFcn = createCallbackFcn(app, @GMresultMenuSelected, true);
            app.GMresultMenu.Text = 'GM result';
            app.RealfitresultMenu = uimenu(app.ContextMenu);
            app.RealfitresultMenu.MenuSelectedFcn = createCallbackFcn(app, @RealfitresultMenuSelected, true);
            app.RealfitresultMenu.Text = 'Real fit result';
            app.RealendmembersMenu = uimenu(app.ContextMenu);
            app.RealendmembersMenu.MenuSelectedFcn = createCallbackFcn(app, @RealendmembersMenuSelected, true);
            app.RealendmembersMenu.Text = 'Real endmembers';
            app.HistogramdataMenu = uimenu(app.ContextMenu);
            app.HistogramdataMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramdataMenuSelected, true);
            app.HistogramdataMenu.Text = 'Histogram data';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.RanalyserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ranalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RanalyserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RanalyserUIFigure)
        end
    end
end